classdef google_solar_api_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        PVPROLabelHome                  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        LocationTab                     matlab.ui.container.Tab
        SearchLocationPanel             matlab.ui.container.Panel
        ExportDataButton                matlab.ui.control.Button
        EnterstreetaddressEditField     matlab.ui.control.EditField
        LatitudeSlider                  matlab.ui.control.Slider
        LatitudeSliderLabel             matlab.ui.control.Label
        LongitudeSlider                 matlab.ui.control.Slider
        LongitudeSliderLabel            matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        LatitudeEditField               matlab.ui.control.NumericEditField
        FindButton                      matlab.ui.control.Button
        AnalyseButton                   matlab.ui.control.Button
        FormattedAddressLabel           matlab.ui.control.Label
        ZoomLevelSlider                 matlab.ui.control.Slider
        ZoomLevelSliderLabel            matlab.ui.control.Label
        UIAxesGoogleMap                 matlab.ui.control.UIAxes
        AnalysisTab                     matlab.ui.container.Tab
        AnalysisDetailsTab              matlab.ui.container.TabGroup
        DataLayersTab                   matlab.ui.container.Tab
        SelectZoomTab                   matlab.ui.container.TabGroup
        ZoomLevelTab                    matlab.ui.container.Tab
        LatLngLabel                     matlab.ui.control.Label
        DLLocationLabel                 matlab.ui.control.Label
        SelectZoomLevelDropDown         matlab.ui.control.DropDown
        SelectZoomLevelDropDownLabel    matlab.ui.control.Label
        DataLayersDetailsTab            matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        UIAxesRGB                       matlab.ui.control.UIAxes
        UIAxesDSM                       matlab.ui.control.UIAxes
        UIAxesMask                      matlab.ui.control.UIAxes
        UIAxesAnnualFlux                matlab.ui.control.UIAxes
        ViewDataLayerTab                matlab.ui.container.Tab
        StatsPanelDL                    matlab.ui.container.Panel
        UnitLabel                       matlab.ui.control.Label
        MeanLabel                       matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        SelectDataPanelDL               matlab.ui.container.Panel
        SelectDataLayerDropDown         matlab.ui.control.DropDown
        SelectDataLayerDropDownLabel    matlab.ui.control.Label
        UIAxesDLRaw                     matlab.ui.control.UIAxes
        UIAxesDL3D                      matlab.ui.control.UIAxes
        UIAxesDLContour                 matlab.ui.control.UIAxes
        UIAxesDLDistribution            matlab.ui.control.UIAxes
        MonthlyFluxTab                  matlab.ui.container.Tab
        StatsPanelMonthly               matlab.ui.container.Panel
        MinLabelMonthly                 matlab.ui.control.Label
        MaxLabelMonthly                 matlab.ui.control.Label
        MeanLabelMonthly                matlab.ui.control.Label
        UnitLabelMonthly                matlab.ui.control.Label
        SelectDatePanelMonthly          matlab.ui.container.Panel
        RoofmaskLabelMonthly            matlab.ui.control.Label
        SwitchRoofMaskMonthly           matlab.ui.control.Switch
        SelectMonthDropDown             matlab.ui.control.DropDown
        SelectMonthDropDownLabel        matlab.ui.control.Label
        UIAxesMonthlyFlux               matlab.ui.control.UIAxes
        UIAxesMonthly3D                 matlab.ui.control.UIAxes
        UIAxesMonthlyContour            matlab.ui.control.UIAxes
        UIAxesMonthlyDistribution       matlab.ui.control.UIAxes
        HourlyShadeTab                  matlab.ui.container.Tab
        PlayButton                      matlab.ui.control.Button
        SelectDatePanelHourly           matlab.ui.container.Panel
        RoofmaskLabelHourly             matlab.ui.control.Label
        SwitchRoofMaskHourly            matlab.ui.control.Switch
        GetHourlyShadeButton            matlab.ui.control.Button
        SelectHourDropDownHourly        matlab.ui.control.DropDown
        SelectMonthDropDown_2Label_3    matlab.ui.control.Label
        SelectDayDropDownHourly         matlab.ui.control.DropDown
        SelectMonthDropDown_2Label_2    matlab.ui.control.Label
        SelectMonthDropDownHourly       matlab.ui.control.DropDown
        SelectMonthDropDown_2Label      matlab.ui.control.Label
        UIAxesHourlyShadeAnimation      matlab.ui.control.UIAxes
        UIAxesHourlyShadeData           matlab.ui.control.UIAxes
        UIAxesHourlyShadeVisibility     matlab.ui.control.UIAxes
        UIAxesHourlyShadeDistribution   matlab.ui.control.UIAxes
        BuildingInsightsTab             matlab.ui.container.Tab
        PanelCapacityDropDown           matlab.ui.control.DropDown
        PanelCapacityDropDownLabel      matlab.ui.control.Label
        SolarPotentialPanel             matlab.ui.container.Panel
        UITableSolarPotential           matlab.ui.control.Table
        PanelInfo                       matlab.ui.container.Panel
        UITablePanelStats               matlab.ui.control.Table
        RoofSegmentInfoPanel            matlab.ui.container.Panel
        UITableRoofSegmentInfo          matlab.ui.control.Table
        WholeRoofInfoPanel              matlab.ui.container.Panel
        UITableRoofStats                matlab.ui.control.Table
        BuildingInfoPanel               matlab.ui.container.Panel
        UITableBuildingInformation      matlab.ui.control.Table
        ImageryInfoPanel                matlab.ui.container.Panel
        UITableImageryInfo              matlab.ui.control.Table
        TabGroupBI                      matlab.ui.container.TabGroup
        GeneralTabBI                    matlab.ui.container.Tab
        TabGroupWholeRoofBI             matlab.ui.container.TabGroup
        WholeRoofTabBI                  matlab.ui.container.Tab
        TabGroupWholeRoofLocationsBI    matlab.ui.container.TabGroup
        PanelLocationsTab               matlab.ui.container.Tab
        OptimiseButtonGroup             matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoButton                        matlab.ui.control.RadioButton
        RefreshButton                   matlab.ui.control.Button
        PercentofMaxArrayEditField      matlab.ui.control.NumericEditField
        PercentofMaxArrayEditFieldLabel  matlab.ui.control.Label
        EnergyProductionkWhyearEditField  matlab.ui.control.NumericEditField
        EnergyProductionkWhyearEditFieldLabel  matlab.ui.control.Label
        NumberofPanelsEditField         matlab.ui.control.NumericEditField
        NumberofPanelsSlider            matlab.ui.control.Slider
        NumberofPanelsSliderLabel       matlab.ui.control.Label
        PanelLocationsandEnergyProductionkWhYear  matlab.ui.container.Panel
        QuantilesTab                    matlab.ui.container.Tab
        QuantilePercentageContributionPanel  matlab.ui.container.Panel
        UITableSunshineQuantilePctBI    matlab.ui.control.Table
        UIAxesSunshineQuantilesBI       matlab.ui.control.UIAxes
        RoofSegmentsTabBI               matlab.ui.container.Tab
        TabGroupRoofSegmentsParametersBI  matlab.ui.container.TabGroup
        LocationsTabRoofSegmentsBI      matlab.ui.container.Tab
        SegmentIndexPercentofMaxArrayEditField  matlab.ui.control.NumericEditField
        PercentofMaxArrayEditField_2Label  matlab.ui.control.Label
        SegmentIndexEnergyProductionkWhyearEditField  matlab.ui.control.NumericEditField
        EnergyProductionkWhyearEditField_2Label  matlab.ui.control.Label
        SegmentIndexRefreshButton       matlab.ui.control.Button
        SegmentIndexEditField           matlab.ui.control.NumericEditField
        NumberofSegmentsbyIndexSlider   matlab.ui.control.Slider
        NumberofSegmentsbyIndexSliderLabel  matlab.ui.control.Label
        RoofSegmentLocationsPanel       matlab.ui.container.Panel
        ParametersTabBI                 matlab.ui.container.Tab
        UIAxesRoofSegmentAreaHeightBI   matlab.ui.control.UIAxes
        UIAxesRoofSegmentOrientationsBI  matlab.ui.control.UIAxes
        PanelDistributionTab            matlab.ui.container.Tab
        UIAxesPanelConfigsPanelsPerSegmentBI  matlab.ui.control.UIAxes
        PanelConfigTabBI                matlab.ui.container.Tab
        TabGroupPanelConfigDetailsBI    matlab.ui.container.TabGroup
        EnergyProductionTabBI           matlab.ui.container.Tab
        UIAxesPanelConfigsProductionTotalBI  matlab.ui.control.UIAxes
        UIAxesPanelConfigsProductionPerPanelBI  matlab.ui.control.UIAxes
        ParametersPanelConfigTabBI      matlab.ui.container.Tab
        TabGroupPanelConfigStatsTableBI  matlab.ui.container.TabGroup
        StatisticsTab                   matlab.ui.container.Tab
        TabGroupArraySelectionStatistics  matlab.ui.container.TabGroup
        MaxArrayPanelConfigsStatisticsTab  matlab.ui.container.Tab
        MaxArrayIndividualPanelStatistics  matlab.ui.container.Panel
        UITableIndividualPanelsStatsBI  matlab.ui.control.Table
        UIAxesPanelConfigsEnergyPerPanelHistBI  matlab.ui.control.UIAxes
        SelectedArrayPanelConfigsStatisticsTab  matlab.ui.container.Tab
        SelectedArrayIndividualPanelsStatistics  matlab.ui.container.Panel
        UITableSelectedPanels           matlab.ui.control.Table
        UIAxesSelectedPanelsHist        matlab.ui.control.UIAxes
        IndividualPanelsTab             matlab.ui.container.Tab
        TabGroupArraySelectionIndividualPanels  matlab.ui.container.TabGroup
        MaxArrayTab                     matlab.ui.container.Tab
        IndividualPanelsMaxArray        matlab.ui.container.Panel
        UITableIndividualPanelsBI       matlab.ui.control.Table
        SelectedPanelsTab               matlab.ui.container.Tab
        IndividualPanelsSelectedArray   matlab.ui.container.Panel
        UITableSelectedArray            matlab.ui.control.Table
        EconomicAnalysisinUSTab         matlab.ui.container.Tab
        APICoverageTab                  matlab.ui.container.Tab
        APICoverageMap                  matlab.ui.container.Panel
        AboutTab                        matlab.ui.container.Tab
        PVPROLabelTitle                 matlab.ui.control.Label
        HyperlinkRef1                   matlab.ui.control.Hyperlink
        HyperlinkRef2                   matlab.ui.control.Hyperlink
        HyperlinkRef3                   matlab.ui.control.Hyperlink
        HyperlinkRef4                   matlab.ui.control.Hyperlink
        HyperlinkREf5                   matlab.ui.control.Hyperlink
        ImageSunLogoAbout               matlab.ui.control.Image
        ThLabelDescription              matlab.ui.control.Label
    end

    
    properties (Access = private)
        % GOOGLE MAPS API Key
        GM_API_KEY
        % Google Maps URLs
        GoogleMapsBaseURL = 'https://maps.googleapis.com/maps/api/geocode/json?address=%s&key=';
        GoogleMapsReverseGeocodeURL = 'https://maps.googleapis.com/maps/api/geocode/json?latlng=%s&key=';
        SolarAPIBuildingInsightsURL = 'https://solar.googleapis.com/v1/buildingInsights:findClosest?';
        SolarAPIDataLayersURL = 'https://solar.googleapis.com/v1/dataLayers:get?';
        SolarAPIDataLayersAccessURL = 'https://solar.googleapis.com/v1/geoTiff:get?';
        Quality = ["HIGH","MEDIUM","LOW"];
        % Lat and Lng
        SelectedLatitude
        SelectedLongitude
        ZoomLevel = 15
        BuildingAddress
        % DATA
        responseDL
        responseBI
        % API Coverage
        GeoCoverageMap
        % DATA LAYERS
        geoTiffZoomLevel = 100
        LayerType = "annualflux"
        % Monthly Flux
        SelectMonthMonthlyFlux = 7
        RoofMaskMonthly = false
        % Hourly Shade
        SelectMonthHourlyShade = 7
        SelectDayHourlyShade = 15
        SelectHourHourlyShade = 12
        RoofMaskHourly = false
        % Hourly Shade Animation
        hImage
        % BUILDING INSIGHTS
        % Locations
        SinglePanelsLocations
        RoofSegmentLocations
        % Panel Capacity
        SelectedPanelCapacity = 400;
        DefaultPanelCapacity = 400;
        % Panel Number
        NumberOfPanels
        MaxNumberOfPanels
        % SegmentNumber
        SegmentNumber
        MaxSegmentNumber
        % Optimise
        OptimiseSelection
    end
    
    methods (Access = private)
        
        % function  [x,y,utmzone] = deg2utm(~, Lat,Lon)
        % % -------------------------------------------------------------------------
        % % [x,y,utmzone] = deg2utm(Lat,Lon)
        % %
        % % Description: Function to convert lat/lon vectors into UTM coordinates (WGS84).
        % % Some code has been extracted from UTM.m function by Gabriel Ruiz Martinez.
        % %
        % % Inputs:
        % %    Lat: Latitude vector.   Degrees.  +ddd.ddddd  WGS84
        % %    Lon: Longitude vector.  Degrees.  +ddd.ddddd  WGS84
        % %
        % % Outputs:
        % %    x, y , utmzone.   See example
        % %
        % % Example 1:
        % %    Lat=[40.3154333; 46.283900; 37.577833; 28.645650; 38.855550; 25.061783];
        % %    Lon=[-3.4857166; 7.8012333; -119.95525; -17.759533; -94.7990166; 121.640266];
        % %    [x,y,utmzone] = deg2utm(Lat,Lon);
        % %    fprintf('%7.0f ',x)
        % %       458731  407653  239027  230253  343898  362850
        % %    fprintf('%7.0f ',y)
        % %      4462881 5126290 4163083 3171843 4302285 2772478
        % %    utmzone =
        % %       30 T
        % %       32 T
        % %       11 S
        % %       28 R
        % %       15 S
        % %       51 R
        % %
        % % Example 2: If you have Lat/Lon coordinates in Degrees, Minutes and Seconds
        % %    LatDMS=[40 18 55.56; 46 17 2.04];
        % %    LonDMS=[-3 29  8.58;  7 48 4.44];
        % %    Lat=dms2deg(mat2dms(LatDMS)); %convert into degrees
        % %    Lon=dms2deg(mat2dms(LonDMS)); %convert into degrees
        % %    [x,y,utmzone] = deg2utm(Lat,Lon)
        % %
        % % Author: 
        % %   Rafael Palacios
        % %   Universidad Pontificia Comillas
        % %   Madrid, Spain
        % % Version: Apr/06, Jun/06, Aug/06, Aug/06
        % % Aug/06: fixed a problem (found by Rodolphe Dewarrat) related to southern 
        % %    hemisphere coordinates. 
        % % Aug/06: corrected m-Lint warnings
        % %-------------------------------------------------------------------------
        % 
        % % Argument checking
        % %
        % % error(nargchk(2, 2, nargin));
        % narginchk(2, 2);  %2 arguments required
        % n1=length(Lat);
        % n2=length(Lon);
        % if (n1~=n2)
        %    error('Lat and Lon vectors should have the same length');
        % end  
        % 
        % 
        % 
        % % Memory pre-allocation
        % %
        % x=zeros(n1,1);
        % y=zeros(n1,1);
        % utmzone(n1,:)='60 X';
        % 
        % % Main Loop
        % %
        % for i=1:n1
        %    la=Lat(i);
        %    lo=Lon(i);
        % 
        %    sa = 6378137.000000 ; sb = 6356752.314245;
        % 
        %    %e = ( ( ( sa ^ 2 ) - ( sb ^ 2 ) ) ^ 0.5 ) / sa;
        %    e2 = ( ( ( sa ^ 2 ) - ( sb ^ 2 ) ) ^ 0.5 ) / sb;
        %    e2cuadrada = e2 ^ 2;
        %    c = ( sa ^ 2 ) / sb;
        %    %alpha = ( sa - sb ) / sa;             %f
        %    %ablandamiento = 1 / alpha;   % 1/f
        % 
        %    lat = la * ( pi / 180 );
        %    lon = lo * ( pi / 180 );
        % 
        %    Huso = fix( ( lo / 6 ) + 31);
        %    S = ( ( Huso * 6 ) - 183 );
        %    deltaS = lon - ( S * ( pi / 180 ) );
        % 
        %    if (la<-72), Letra='C';
        %    elseif (la<-64), Letra='D';
        %    elseif (la<-56), Letra='E';
        %    elseif (la<-48), Letra='F';
        %    elseif (la<-40), Letra='G';
        %    elseif (la<-32), Letra='H';
        %    elseif (la<-24), Letra='J';
        %    elseif (la<-16), Letra='K';
        %    elseif (la<-8), Letra='L';
        %    elseif (la<0), Letra='M';
        %    elseif (la<8), Letra='N';
        %    elseif (la<16), Letra='P';
        %    elseif (la<24), Letra='Q';
        %    elseif (la<32), Letra='R';
        %    elseif (la<40), Letra='S';
        %    elseif (la<48), Letra='T';
        %    elseif (la<56), Letra='U';
        %    elseif (la<64), Letra='V';
        %    elseif (la<72), Letra='W';
        %    else 
        %        Letra='X';
        %    end
        % 
        %    a = cos(lat) * sin(deltaS);
        %    epsilon = 0.5 * log( ( 1 +  a) / ( 1 - a ) );
        %    nu = atan( tan(lat) / cos(deltaS) ) - lat;
        %    v = ( c / ( ( 1 + ( e2cuadrada * ( cos(lat) ) ^ 2 ) ) ) ^ 0.5 ) * 0.9996;
        %    ta = ( e2cuadrada / 2 ) * epsilon ^ 2 * ( cos(lat) ) ^ 2;
        %    a1 = sin( 2 * lat );
        %    a2 = a1 * ( cos(lat) ) ^ 2;
        %    j2 = lat + ( a1 / 2 );
        %    j4 = ( ( 3 * j2 ) + a2 ) / 4;
        %    j6 = ( ( 5 * j4 ) + ( a2 * ( cos(lat) ) ^ 2) ) / 3;
        %    alfa = ( 3 / 4 ) * e2cuadrada;
        %    beta = ( 5 / 3 ) * alfa ^ 2;
        %    gama = ( 35 / 27 ) * alfa ^ 3;
        %    Bm = 0.9996 * c * ( lat - alfa * j2 + beta * j4 - gama * j6 );
        %    xx = epsilon * v * ( 1 + ( ta / 3 ) ) + 500000;
        %    yy = nu * v * ( 1 + ta ) + Bm;
        % 
        %    if (yy<0)
        %        yy=9999999+yy;
        %    end
        % 
        %    x(i)=xx;
        %    y(i)=yy;
        %    utmzone(i,:)=sprintf('%02d %c',Huso,Letra);
        % end
        % end             
            
        function [XX, YY, M, Mcolor] = get_google_map(~, lat, lon, varargin)
            % Gets a google map using the Google Static Maps API
            %
            % REQUIREMENTS:
            % 1)  deg2utm() (Conversion from degrees to UTM coordinates, found on
            % MATLAB Central.
            %
            % 2)  THIS FUNCTION REQUIRES A GOOGLE MAPS API KEY! (READ ON)
            % To query and return a Google Map using the Google Static Map API, Google
            % requires that you have a valid MAPS API KEY. The key is free and only
            % requires a Google account and a valid domain (e.g. http://ccom.unh.edu )
            % from which your queries will come. Note, this last requirement places
            % serious limitations on the portability of this function, as it is
            % necessarily tied to a domain and therefore (usually) a physical location.
            %
            % A Google Maps API Key may be obtained here:
            % http://code.google.com/apis/maps/signup.html
            %
            % Once you obtain your key, replace the text assigned to the MAPS_API_KEY
            % variable below with your own.
            %
            % USAGE:
            %
            % [XX YY M Mcolor] = get_google_map( latitude, longitude, ...
            %                              Property, Value,...
            %                              Property, Value)
            %
            % PROPERTIES:
            %    Height (640)      Height of the image in pixels (max 640)
            %    Width  (640)      Width of the image in pixels (max 640)
            %    Zoom (15)         Zoom Level (1-19) Zoom of 15 = ~3.4m/pixel.
            %    MapType ('satellite')  Type of map to return. Any of [roadmap, mobile,
            %                           satellite, terrain, hybrid, mapmaker-roadmap,
            %                           mapmaker-hybrid) See the Google Maps API for
            %                           more information.
            %    Marker            The marker argument is a text string with fields
            %                      conforming to the Google Maps API. The following are
            %                      valid examples:
            %                      '43.0738740,-70.713993' (dflt midsize orange marker)
            %                      '43.0738740,-70.713993,blue' (midsize blue marker)
            %                      '43.0738740,-70.713993,yellowa' (midsize yellow
            %                      marker with label "A")
            %                      '43.0738740,-70.713993,tinyredb' (tiny red marker
            %                      with label "B")
            %    APIKey           - (string) set your own API key which you obtained from Google:
            %                     http://developers.google.com/maps/documentation/staticmaps/#api_key
            %                     This will enable up to 25,000 map requests per day,
            %                     compared to a few hundred requests without a key.
            %                     To set the key, use:
            %                     get_google_map('APIKey','SomeLongStringObtaindFromGoogle')
            %                     You need to do this only once to set the key.
            %                     To disable the use of a key, use:
            %                     get_google_map('APIKey','') [This option is borrowed
            %                     from plot_google_map(), with many thanks.]
            
            %
            % RESULTS:
            %    XX                Estimate of Easting pixel coordinates in UTM
            %    YY                Estimate of Northing pixel coordinates in UTM
            %    M                 Image data matrix (height x width)
            %    Mcolor            Image colormap
            %
            % EXAMPLE:
            %
            %    % Get the map
            %    [XX YY M Mcolor] = get_google_map(43.0738740,-70.713993);
            %    % Plot the result
            %    imagesc(XX,YY,M); shading flat;
            %    colormap(Mcolor)
            %
            % Acknowledgements:
            % Zohar Bar-Yehuda for his submission of plot_google_map() which is, in
            % many ways, a great improvement on this effort. In particular, his
            % treatment of the Google Maps API has been reproduced here.
            %
            % References:
            % https://developers.google.com/maps/documentation/static-maps/
            % http://www.mathworks.com/matlabcentral/fileexchange/27627-zoharby-plot-google-map
            %
            % KNOWN BUGS AND ISSUES:
            % 1) MAP Bounds. The bounds reported by this function are an approximation
            % based on repeated trials, as there is no way (to my knowledge) in which
            % to query Google for the bounds of the map image exactly. This method is
            % potentially ripe with errors. For example, it may not produce correct
            % bounds at latitudes far from 40 degrees N/S where it was calibrated.
            % 2) No provision has been programmed to handle locations near the date
            % line, nor boundaries between UTM zones. Images that cross these will
            % likely produce improper bounds.
            %
            % * Val Schmidt
            % * University of New Hampshire
            % * Center for Coastal and Ocean Mapping
            % * 2009, 2016
            
            
            % persistent apiKey
            % if isnumeric(apiKey)
            %     % first run, check if API key file exists
            %     if exist('api_key.mat','file')
            %         load api_key
            %     else
            %         apiKey = '';
            %     end
            % end
            
            
            cal_distance = 30;  % in degrees of latitude
            degpermeter = 1/60*1/1852;
            
            % HANDLE ARGUMENTS
            height = 640;
            width = 640;
            zoomlevel = 17;
            maptype = 'satellite';
            
            markeridx = 1;
            markerlist = {};
            if nargin > 2
                for idx = 1:2:length(varargin)
                    switch varargin{idx}
                        case 'Height'
                            height = varargin{idx+1};
                        case 'Width'
                            width = varargin{idx+1};
                        case 'Zoom'
                            zoomlevel = varargin{idx+1};
                        case 'MapType'
                            maptype = varargin{idx+1};
                        case 'Marker'
                            markerlist{markeridx} = varargin{idx+1};
                            markeridx = markeridx + 1;
                        case {'APIKey', 'apikey'}
                            apiKey = varargin{idx+1}; % set new key
                            % save key to file
                            % funcFile = which('get_google_map.m');
                            % pth = fileparts(funcFile);
                            % keyFile = fullfile(pth,'api_key.mat');
                            % save(keyFile,'apiKey')
                        otherwise
                            error(['Unrecognized variable: ' varargin{idx}])
                    end
                end
            end
            
            
            if zoomlevel <1 || zoomlevel > 19
                error('Zoom Level must be > 0 and < 20.')
            end
            
            if mod(zoomlevel,1) ~= 0
                zoomlevel = round(zoomlevel);
                warning(['Zoom Level must be an integer. Rounding to '...
                    num2str(zoomlevel)]);
            end
            
            % CONSTRUCT QUERY URL
            preamble = 'http://maps.googleapis.com/maps/api/staticmap';
            location = ['?center=' num2str(lat,10) ',' num2str(lon,10)];
            cal_location = ['?center=' num2str(lat - degpermeter*cal_distance,10) ',' num2str(lon,10)];
            
            zoom = ['&zoom=' num2str(zoomlevel)];
            size = ['&size=' num2str(width) 'x' num2str(height)];
            maptype = ['&maptype=' maptype ];
            markers = '&markers=';
            for idx = 1:length(markerlist)
                if idx < length(markerlist)
                    markers = [markers markerlist{idx} '%7C'];
                else
                    markers = [markers markerlist{idx}];
                end
            end
            format = '&format=png';
            if ~isempty(apiKey)
                key = ['&key=' apiKey];
            else
                key = '';
            end
            sensor = '&sensor=false';
            % url = [preamble location zoom size maptype format markers sensor key];
            url = strjoin([preamble location zoom size maptype format markers sensor key], '');
            % cal_url = [preamble cal_location zoom size maptype format markers sensor key];
            cal_url = strjoin([preamble cal_location zoom size maptype format markers sensor key], '');
            
            % GET THE IMAGE
            % disp(url) 
            % disp(app.GM_API_KEY);
            url = convertCharsToStrings(url); % Ensure `url` is a string scalar
            [M, Mcolor] = webread(url);
            M = cast(M,'double');
            
            % ESTIMATE BOUNDS OF IMAGE:
            % We get 2 images instead of just one, separated by a known distance. Then
            % we cross-correlate one with the other to find the distance between the
            % two images in pixels. Divide one by the other and you have distance per
            % pixel. From this and the center point of we can calculate the coordinates
            % of each.
            
            % GET THE CAL IMAGE
            [Mcal, ~] = webread(cal_url);
            Mcal = cast(Mcal,'double');
            
            % Cross correlate a column in the middle of the data to get the shift
            % between them in pixels.
            comparecol = floor(width/2);
            c = xcorr(M(20:end,comparecol),Mcal(20:end,comparecol)); % skip first 20 pixels
            c = fftshift(c);
            [~, pixels] = max(c(1:floor(end/2)));
            
            dx = cal_distance/pixels;
            
            % Convert coordinates to UTM.
            [lonutm, latutm, ~] = deg2utm(lat,lon);
            % disp(lonutm)
            L = dx*(width-1);
            W = dx*(height-1);
            XX = 0:dx:L;
            YY = 0:dx:W;
            XX = XX - mean(XX) + lonutm;
            YY = YY - mean(YY) + latutm;
        end
        
        function showDataLayers(app, response)

            % GENERAL
            % app.UIAxesRGB app.UIAxesDSM app.UIAxesMask app.UIAxesAnnualFlux
            if strcmp(app.UIAxesRGB.Visible, 'off')
                app.UIAxesRGB.Visible = 'on';
            end
            if strcmp(app.UIAxesDSM.Visible, 'off')
                app.UIAxesDSM.Visible = 'on';
            end
            if strcmp(app.UIAxesMask.Visible, 'off')
                app.UIAxesMask.Visible = 'on';
            end
            if strcmp(app.UIAxesAnnualFlux.Visible, 'off')
                app.UIAxesAnnualFlux.Visible = 'on';
            end

            if isfield(response, 'rgbUrl')
                rgb_url = sprintf('%s&key=%s', response.rgbUrl, app.GM_API_KEY);
                % weboptions_obj = weboptions('ContentType', 'image');
                % rgb_data = webread(rgb_url, weboptions_obj);
                rgb_data = webread(rgb_url);
                cla(app.UIAxesRGB);
                imshow(rgb_data, 'Parent', app.UIAxesRGB); 
                axis(app.UIAxesRGB, 'tight');
                axis(app.UIAxesRGB, 'image');
                app.UIAxesRGB.Title.String = 'RGB Aerial Image';
                app.UIAxesRGB.Title.FontSize = 10;

            end

            % Process DSM layer
            if isfield(response, 'dsmUrl')
                dsm_filename = 'temp_dsm.tif';
                dsm_url = sprintf('%s&key=%s', response.dsmUrl, app.GM_API_KEY);
                websave(dsm_filename, dsm_url);
                [dsm_data, ~] = readgeoraster(dsm_filename);
                cla(app.UIAxesDSM);
                imagesc(dsm_data,'Parent',app.UIAxesDSM);
                axis(app.UIAxesDSM, 'tight');
                axis(app.UIAxesDSM, 'image');
                colorbar(app.UIAxesDSM);
                app.UIAxesDSM.Title.String = 'Digital Surface Model (m above EGM96 geoid)';
                app.UIAxesDSM.Title.FontSize = 10;
                delete(dsm_filename);
            end

            % Process Mask layer
            if isfield(response, 'maskUrl')
                mask_filename = 'temp_mask.tif';
                mask_url = sprintf('%s&key=%s', response.maskUrl, app.GM_API_KEY);
                websave(mask_filename, mask_url);
                [mask_data, ~] = readgeoraster(mask_filename);
                cla(app.UIAxesMask);
                imagesc(mask_data,'Parent',app.UIAxesMask);
                axis(app.UIAxesMask, 'tight');
                axis(app.UIAxesMask, 'image');
                colorbar(app.UIAxesMask);                
                app.UIAxesMask.Title.String = 'Roof Mask';
                app.UIAxesMask.Title.FontSize = 10;
                delete(mask_filename);
            end

            % Process Annual Flux layer
            if isfield(response, 'annualFluxUrl')
                flux_filename = 'temp_flux.tif';
                flux_url = sprintf('%s&key=%s', response.annualFluxUrl, app.GM_API_KEY);
                websave(flux_filename, flux_url);
                [flux_data, ~] = readgeoraster(flux_filename);
                cla(app.UIAxesAnnualFlux);
                imagesc(flux_data,'Parent',app.UIAxesAnnualFlux);
                axis(app.UIAxesAnnualFlux, 'tight');
                axis(app.UIAxesAnnualFlux, 'image');
                colorbar(app.UIAxesAnnualFlux);
                app.UIAxesAnnualFlux.Title.String = 'Annual Solar Flux (kWh/kW/year)';
                app.UIAxesAnnualFlux.Title.FontSize = 10;
                delete(flux_filename);
            end
            
        end
        
        function showSelectDataLayer(app, response)

            % VIEW DATA LAYERS
            % app.UIAxesDLRaw app.UIAxesDL3D app.UIAxesDLContour app.UIAxesDLDistribution
            if strcmp(app.UIAxesDLRaw.Visible, 'off')
                app.UIAxesDLRaw.Visible = 'on';
            end
            if strcmp(app.UIAxesDL3D.Visible, 'off')
                app.UIAxesDL3D.Visible = 'on';
            end
            if strcmp(app.UIAxesDLContour.Visible, 'off')
                app.UIAxesDLContour.Visible = 'on';
            end
            if strcmp(app.UIAxesDLDistribution.Visible, 'off')
                app.UIAxesDLDistribution.Visible = 'on';
            end
            % app.SelectDataLayerDropDown
            if strcmp(app.SelectDataLayerDropDown.Visible, 'off')
                app.SelectDataLayerDropDown.Visible = 'on';
            end

            % Data Layers: Select Layer Init
            % layer_type can be 'dsm', 'mask', 'annualFlux', or 'hourlyShade'
            try
                % Select URL based on layer type
                switch lower(app.LayerType)
                    case 'dsm'
                        if ~isfield(response, 'dsmUrl')
                            error('DSM URL not found in response');
                        end
                        url = response.dsmUrl;
                    case 'mask'
                        if ~isfield(response, 'maskUrl')
                            error('Mask URL not found in response');
                        end
                        url = response.maskUrl;
                    case 'annualflux'
                        if ~isfield(response, 'annualFluxUrl')
                            error('Annual Flux URL not found in response');
                        end
                        url = response.annualFluxUrl;
                    otherwise
                        error('Invalid layer type. Must be dsm, mask, annualFlux, or hourlyShade');
                end
            catch ME
                error('Error processing GeoTIFF: %s', ME.message);
            end

            % Add API key to URL
            geotiff_url = sprintf('%s&key=%s', url, app.GM_API_KEY);
            
            % Download the GeoTIFF file
            filename = 'temp_geotiff.tif';
            websave(filename, geotiff_url);
            
            % Read the GeoTIFF file
            [A, R] = readgeoraster(filename);

            % Create figure with multiple subplots for different visualizations
            % figure('Name', ['Solar API GeoTIFF Visualization - ' upper(layer_type)]);
            
            % Plot 1: Select layer - Basic visualization -> DL Raw
            imagesc(A,'Parent', app.UIAxesDLRaw);
            colorbar(app.UIAxesDLRaw);
            app.UIAxesDLRaw.Title.String = 'Raw Data Visualization';
            app.UIAxesDLRaw.Title.FontSize = 10;
            axis(app.UIAxesDLRaw, 'tight')
            axis(app.UIAxesDLRaw, 'equal')

            % Plot 2: Select layer - Surface plot
            surf(A,'Parent', app.UIAxesDL3D ,'EdgeColor', 'none');
            colorbar(app.UIAxesDL3D);
            app.UIAxesDL3D.Title.String  = '3D Surface Plot';
            app.UIAxesDL3D.Title.FontSize = 10;

            % Plot 3: Select layer - Contour plot
            contourf(A, 'Parent', app.UIAxesDLContour);
            colorbar(app.UIAxesDLContour);
            app.UIAxesDLContour.Title.String = 'Contour Plot';
            app.UIAxesDLContour.Title.FontSize = 10;
            axis(app.UIAxesDLContour, 'tight')
            axis(app.UIAxesDLContour, 'equal')
            axis(app.UIAxesDLContour, 'ij')
            %axis equal tight;
                    
            % Plot 4: Select layer - Histogram of values
            histogram(A(:), 'Parent', app.UIAxesDLDistribution);
            
            % Calculate mean, min, and max
            meanVal = mean(A(:), 'omitnan');
            minVal = min(A(:), [], 'omitnan');
            maxVal = max(A(:), [], 'omitnan');
            
            % Add mean value line
            hold(app.UIAxesDLDistribution, 'on');
            if app.LayerType == "annualflux" || app.LayerType == "dsm"
                line(app.UIAxesDLDistribution, [meanVal, meanVal], ylim(app.UIAxesDLDistribution), ...
                'Color', 'r', 'LineStyle', '--', 'LineWidth', 1.5);
            end
            hold(app.UIAxesDLDistribution, 'off');
            
            % Customize title and labels
            app.UIAxesDLDistribution.Title.String = 'Data Distribution';
            app.UIAxesDLDistribution.Title.FontSize = 10;
            app.UIAxesDLDistribution.XLabel.String = 'Value';
            app.UIAxesDLDistribution.YLabel.String = 'Frequency';
            
            % Set custom x-axis ticks
            % app.UIAxesDLDistribution.XTick = [minVal, meanVal, maxVal];
            % app.UIAxesDLDistribution.XTickLabel = {'Min', 'Mean', 'Max'};

            if app.LayerType == "annualflux"
                unit = "kWh/kW/year";
                % Set custom x-axis ticks
                app.UIAxesDLDistribution.XTick = [minVal, meanVal, maxVal];
                app.UIAxesDLDistribution.XLabel.String = 'Energy (kWh/kW/year)';
                app.UnitLabel.Text = unit;
                app.MinLabel.Text = ['Min: ' num2str(round(min(A(:)),0))];
                app.MaxLabel.Text = ['Max: ' num2str(round(max(A(:)),0))];
                if isnan(round(mean(A(:)),0))
                    app.MeanLabel.Text = 'Mean: -';
                else
                    app.MeanLabel.Text = ['Mean: ' num2str(round(mean(A(:)),0))];
                end  
            elseif app.LayerType == "dsm"
                % Set custom x-axis ticks
                app.UIAxesDLDistribution.XTick = [minVal, meanVal, maxVal];
                app.UIAxesDLDistribution.XLabel.String = 'Height (m above sea level)';
                unit = "m above EGM96 geoid";
                app.UnitLabel.Text = unit;
                app.MinLabel.Text = ['Min: ' num2str(round(min(A(:)),0))];
                app.MaxLabel.Text = ['Max: ' num2str(round(max(A(:)),0))];
                if isnan(round(mean(A(:)),0))
                    app.MeanLabel.Text = 'Mean: -';
                else
                    app.MeanLabel.Text = ['Mean: ' num2str(round(mean(A(:)),0))];
                end  
            elseif app.LayerType == "mask"
                app.UIAxesDLDistribution.XTick = [minVal maxVal];
                unit = "-";
                app.UnitLabel.Text = unit;
                app.MinLabel.Text = 'Min: -';
                app.MaxLabel.Text = 'Max: -';
                app.MeanLabel.Text = 'Mean: -';
            end

            % Clean up temporary file
            delete(filename);
        end
        
        function showDataLayersMonthlyFlux(app, response)

            % MONTHLY FLUX
            % app.UIAxesMonthlyFlux app.UIAxesMonthly3D
            % app.UIAxesMonthlyContour app.UIAxesMonthlyDistribution
            if strcmp(app.UIAxesMonthlyFlux.Visible, 'off')
                app.UIAxesMonthlyFlux.Visible = 'on';
            end
            if strcmp(app.UIAxesMonthly3D.Visible, 'off')
                app.UIAxesMonthly3D.Visible = 'on';
            end
            if strcmp(app.UIAxesMonthlyContour.Visible, 'off')
                app.UIAxesMonthlyContour.Visible = 'on';
            end
            if strcmp(app.UIAxesMonthlyDistribution.Visible, 'off')
                app.UIAxesMonthlyDistribution.Visible = 'on';
            end
            % app.SelectMonthDropDown app.SwitchRoofMaskMonthly
            if strcmp(app.SelectMonthDropDown.Visible, 'off')
                app.SelectMonthDropDown.Visible = 'on';
            end
            if strcmp(app.SwitchRoofMaskMonthly.Visible, 'off')
                app.SwitchRoofMaskMonthly.Visible = 'on';
            end

            % Data Layer Monthly Flux Init
            % selected_month should be 1-12 (January-December)
            if ~isfield(response, 'monthlyFluxUrl')
                error('Monthly Flux URL not found in response');
            end

            % Month names for display
            month_names = {'January', 'February', 'March', 'April', 'May', 'June', ...
                'July', 'August', 'September', 'October', 'November', 'December'};
            
            % Download and read the GeoTIFF
            flux_filename = 'temp_monthly_flux.tif';
            flux_url = sprintf('%s&key=%s', response.monthlyFluxUrl, app.GM_API_KEY);
            websave(flux_filename, flux_url);
            [flux_data, R] = readgeoraster(flux_filename);
            
            % Extract the selected month's data (band)
            monthly_data = flux_data(:,:,app.SelectMonthMonthlyFlux);

            % Apply roof mask if requested
            if app.RoofMaskMonthly == true
                monthly_data = app.apply_roof_mask(response, app.GM_API_KEY, monthly_data, 'monthly_flux');
            end

            % Plot 1: Monthly - Basic visualization
            imagesc(monthly_data, 'AlphaData', ~isnan(monthly_data), 'Parent', app.UIAxesMonthlyFlux);
            colorbar(app.UIAxesMonthlyFlux);
            app.UIAxesMonthlyFlux.Title.String = sprintf('Solar Flux - %s', month_names{app.SelectMonthMonthlyFlux});
            app.UIAxesMonthlyFlux.Title.FontSize = 10;
            axis(app.UIAxesMonthlyFlux, 'tight')
            axis(app.UIAxesMonthlyFlux, 'equal')
            % axis equal tight;

            % Plot 2: Monthly - Surface plot
            surf(monthly_data, 'Parent', app.UIAxesMonthly3D,'EdgeColor', 'none');
            colorbar(app.UIAxesMonthly3D);
            app.UIAxesMonthly3D.Title.String = '3D Surface Plot';
            app.UIAxesMonthly3D.Title.FontSize = 10;

            % Plot 3: Monthly - Contour plot
            contourf(monthly_data, 'Parent', app.UIAxesMonthlyContour);
            colorbar(app.UIAxesMonthlyContour);
            app.UIAxesMonthlyContour.Title.String = 'Contour Plot';
            app.UIAxesMonthlyContour.Title.FontSize = 10;
            axis(app.UIAxesMonthlyContour, 'tight')
            axis(app.UIAxesMonthlyContour, 'equal')
            axis(app.UIAxesMonthlyContour, 'ij')

            % Plot 4: Monthly - Histogram
            % Plot: Monthly data distribution histogram
            histogram(monthly_data(:), 'Parent', app.UIAxesMonthlyDistribution);
            
            % Calculate mean, min, and max
            meanMonthly = mean(monthly_data(:), 'omitnan');
            minMonthly = min(monthly_data(:), [], 'omitnan');
            maxMonthly = max(monthly_data(:), [], 'omitnan');
            
            % Add mean value line
            hold(app.UIAxesMonthlyDistribution, 'on');
            line(app.UIAxesMonthlyDistribution, [meanMonthly, meanMonthly], ylim(app.UIAxesMonthlyDistribution), ...
                'Color', 'r', 'LineStyle', '--', 'LineWidth', 1.5);
            hold(app.UIAxesMonthlyDistribution, 'off');
            
            % Customize title and labels
            app.UIAxesMonthlyDistribution.Title.String = 'Data Distribution';
            app.UIAxesMonthlyDistribution.Title.FontSize = 10;
            app.UIAxesMonthlyDistribution.XLabel.String = 'kWh/kW/year';
            app.UIAxesMonthlyDistribution.YLabel.String = 'Frequency';
            
            % Set custom x-axis ticks
            app.UIAxesMonthlyDistribution.XTick = [minMonthly, meanMonthly, maxMonthly];
            % app.UIAxesMonthlyDistribution.XTickLabel = {'Min', 'Mean', 'Max'};

            monthlyUnit = "kWh/kW/year";
            app.UnitLabelMonthly.Text = monthlyUnit;
            app.MinLabelMonthly.Text = ['Min: ' num2str(round(min(monthly_data(:)),0))];
            app.MaxLabelMonthly.Text = ['Max: ' num2str(round(max(monthly_data(:)),0))];
            if isnan(round(mean(monthly_data(:)),0))
                app.MeanLabelMonthly.Text = 'Mean: -';
            else
                app.MeanLabelMonthly.Text = ['Mean: ' num2str(round(mean(monthly_data(:)),0))];
            end
            % Clean up
            delete(flux_filename);
        end
        
        function showDataLayersHourlyShade(app, response)
            
            % HOURLY SHADE
            % Enable axes
            % app.UIAxesHourlyShadeData app.UIAxesHourlyShadeVisibility
            % app.UIAxesHourlyShadeValidity app.UIAxesHourlyShadeDistribution
            if strcmp(app.UIAxesHourlyShadeData.Visible, 'off')
                app.UIAxesHourlyShadeData.Visible = 'on';
            end
            if strcmp(app.UIAxesHourlyShadeVisibility.Visible, 'off')
                app.UIAxesHourlyShadeVisibility.Visible = 'on';
            end
            % if strcmp(app.UIAxesHourlyShadeValidity.Visible, 'off')
            %     app.UIAxesHourlyShadeValidity.Visible = 'on';
            % end
            if strcmp(app.UIAxesHourlyShadeDistribution.Visible, 'off')
                app.UIAxesHourlyShadeDistribution.Visible = 'on';
            end
            % Enable controls
            % app.SelectMonthDropDownHourly app.SelectDayDropDownHourly
            % app.SelectHourDropDownHourly app.GetHourlyShadeButton
            % app.SwitchRoofMaskHourly 
            if strcmp(app.SelectMonthDropDownHourly.Visible, 'off')
                app.SelectMonthDropDownHourly.Visible = 'on';
            end
            if strcmp(app.SelectDayDropDownHourly.Visible, 'off')
                app.SelectDayDropDownHourly.Visible = 'on';
            end
            if strcmp(app.SelectHourDropDownHourly.Visible, 'off')
                app.SelectHourDropDownHourly.Visible = 'on';
            end
            if strcmp(app.GetHourlyShadeButton.Visible, 'off')
                app.GetHourlyShadeButton.Visible = 'on';
            end
            if strcmp(app.SwitchRoofMaskHourly.Visible, 'off')
                app.SwitchRoofMaskHourly.Visible = 'on';
            end
            % app.UIAxesHourlyShadeAnimation
            if strcmp(app.UIAxesHourlyShadeAnimation.Visible, 'off')
                app.UIAxesHourlyShadeAnimation.Visible = 'on';
            end
            
            % Hourly Shade
            % month, day, hour
            % month should be 1-12 (January-December)
            % day should be 1-31 (depending on month)
            % hour should be 1-24
            if ~isfield(response, 'hourlyShadeUrls') || isempty(response.hourlyShadeUrls)
                error('Hourly Shade URLs not found in response');
            end

            % Month names for display
            month_names = {'January', 'February', 'March', 'April', 'May', 'June', ...
                'July', 'August', 'September', 'October', 'November', 'December'};

            % Download and read the GeoTIFF for the selected month
            shade_filename = 'temp_hourly_shade.tif';
            shade_url = sprintf('%s&key=%s', response.hourlyShadeUrls{app.SelectMonthHourlyShade}, app.GM_API_KEY);
            websave(shade_filename, shade_url);
            [shade_data, R] = readgeoraster(shade_filename);

            % Extract the selected hour's data and get the specific day's bit
            if app.RoofMaskHourly == true
                % Apply mask to all hourly data
                shade_data = app.apply_roof_mask(response, app.GM_API_KEY, shade_data, 'hourly_shade');
            end
            
            % Extract the selected hour's data and get the specific day's bit
            hourly_data = shade_data(:,:,app.SelectHourHourlyShade);
            day_mask = bitget(hourly_data, app.SelectDayHourlyShade); % Get the bit for the selected day
            day_mask(hourly_data == -9999) = -9999; % Preserve invalid locations

            % Plot 1: Hourly Shade - Basic visualization
            cla(app.UIAxesHourlyShadeData, 'reset')
            if app.RoofMaskHourly == true
                % Create visualization data
                vis_data = zeros(size(day_mask));

                % Check if it's nighttime (all valid pixels are shaded)
                is_night = all(day_mask(hourly_data ~= -9999) == 0);
                
                if is_night
                    vis_data(hourly_data == -9999) = 0.5;  % Non-roof areas
                    vis_data(hourly_data ~= -9999 & day_mask == 0) = 0;  % Shaded roof areas
                    vis_data(hourly_data ~= -9999 & day_mask == 1) = 1;  % Sunny roof areas
    
                    imagesc(vis_data, 'Parent', app.UIAxesHourlyShadeData);
                    colormap(app.UIAxesHourlyShadeData, [61/256 38/256 168/256; 0.7 0.7 0.7]);  % Blue for shade, gray for non-roof
                    cbar = colorbar(app.UIAxesHourlyShadeData);
                    cbar.Ticks = [0.1 0.35];
                    cbar.TickLabels = {'Shade', 'Non-roof'};
                else
                    vis_data(hourly_data == -9999) = 0.5;  % Non-roof areas
                    vis_data(hourly_data ~= -9999 & day_mask == 0) = 0;  % Shaded roof areas
                    vis_data(hourly_data ~= -9999 & day_mask == 1) = 1;  % Sunny roof areas
    
                    imagesc(vis_data, 'Parent', app.UIAxesHourlyShadeData);
                    colormap(app.UIAxesHourlyShadeData, [61/256 38/256 168/256; 0.7 0.7 0.7; 249/256 251/256 20/256]);  % Blue for shade, gray for non-roof, yellow for sun
                    cbar = colorbar(app.UIAxesHourlyShadeData);
                    cbar.Ticks = [0.17 0.5 0.83];
                    cbar.TickLabels = {'Shade', 'Non-roof', 'Sun'};
                end
            else
                % For non-masked data, handle nighttime similarly
                vis_data = day_mask;
                is_night = all(day_mask(hourly_data ~= -9999) == 0);

                if is_night
                    vis_data(hourly_data ~= -9999) = 0;    

                    imagesc(vis_data, 'Parent', app.UIAxesHourlyShadeData);
                    colormap(app.UIAxesHourlyShadeData, [61/256 38/256 168/256]);  % Blue for shade, gray for non-roof, yellow for sun
                    colorbar(app.UIAxesHourlyShadeData,'Ticks', 0.0,...
                        'TickLabels', {'Shade'});
                else
                    % ?
                    % vis_data(hourly_data ~= -9999) = 0.5; 

                    imagesc(vis_data, 'Parent', app.UIAxesHourlyShadeData);
                    colormap(app.UIAxesHourlyShadeData, [61/256 38/256 168/256; 249/256 251/256 20/256]);  % Blue for shade, gray for non-roof, yellow for sun
                    colorbar(app.UIAxesHourlyShadeData,'Ticks', [0.28, 0.72],...
                        'TickLabels', {'Shade', 'Sun'});
                end
            end
            app.UIAxesHourlyShadeData.Title.String = sprintf('Shade Data - %s %d, Hour %d',...
                    month_names{app.SelectMonthHourlyShade}, app.SelectDayHourlyShade, app.SelectHourHourlyShade);
            app.UIAxesHourlyShadeData.Title.FontSize = 10;
            axis(app.UIAxesHourlyShadeData, 'tight')
            axis(app.UIAxesHourlyShadeData, 'equal')

            % Plot 2: Hourly Shade - Binary visualization (sun visibility)
            cla(app.UIAxesHourlyShadeVisibility, 'reset')
            if app.RoofMaskHourly == true
                % Create binary visualization data
                binary_data = zeros(size(day_mask));

                % Check if it's nighttime
                is_night = all(day_mask(hourly_data ~= -9999) == 0);

                if is_night
                    binary_data(hourly_data == -9999) = 0.5;  % Non-roof areas
                    binary_data(hourly_data ~= -9999) = 0;    % All roof areas are shaded
                    imagesc(binary_data, 'Parent', app.UIAxesHourlyShadeVisibility);
                    colormap(app.UIAxesHourlyShadeVisibility, [1 0 0; 0.7 0.7 0.7]); % Red for shade, gray for invalid
                    colorbar(app.UIAxesHourlyShadeVisibility, 'Ticks', [0.1, 0.35],...
                        'TickLabels', {'Shade', 'Invalid'});
                else
                    binary_data(hourly_data == -9999) = 0.5;  % Non-roof areas
                    binary_data(hourly_data ~= -9999 & day_mask == 0) = 0;  % Shaded roof areas
                    binary_data(hourly_data ~= -9999 & day_mask == 1) = 1;  % Sunny roof areas
                    imagesc(binary_data, 'Parent', app.UIAxesHourlyShadeVisibility);
                    colormap(app.UIAxesHourlyShadeVisibility, [1 0 0; 0.7 0.7 0.7; 0 1 0]); % Red for shade, gray for invalid, green for sun
                    colorbar(app.UIAxesHourlyShadeVisibility, 'Ticks', [0.17, 0.5, 0.83],...
                        'TickLabels', {'Shade', 'Invalid', 'Sun'});
                end
            else
                binary_data = day_mask == 1;
                is_night = all(day_mask(hourly_data ~= -9999) == 0);

                if is_night
                    binary_data(hourly_data ~= -9999) = 0;  % All valid areas are shaded
                    binary_data(hourly_data == -9999) = 0.5;
                    imagesc(binary_data, 'Parent', app.UIAxesHourlyShadeVisibility);
                    colormap(app.UIAxesHourlyShadeVisibility, [1 0 0]); % Red for shade
                    colorbar(app.UIAxesHourlyShadeVisibility, 'Ticks', 0.5,...
                        'TickLabels', {'Shade'});
                else
                    binary_data(hourly_data == -9999) = 0.5; % Special value for invalid locations
                    imagesc(binary_data, 'Parent', app.UIAxesHourlyShadeVisibility);
                    colormap(app.UIAxesHourlyShadeVisibility, [1 0 0; 0.7 0.7 0.7; 0 1 0]); % Red for shade, gray for invalid, green for sun
                    colorbar(app.UIAxesHourlyShadeVisibility, 'Ticks', [0.17, 0.5, 0.83],...
                        'TickLabels', {'Shade', 'Invalid', 'Sun'});
                end
            end
            app.UIAxesHourlyShadeVisibility.Title.String = sprintf('Sun Visibility Map %s %d, Hour %d',...
                month_names{app.SelectMonthHourlyShade}, app.SelectDayHourlyShade, app.SelectHourHourlyShade);
            app.UIAxesHourlyShadeVisibility.Title.FontSize = 10;
            axis(app.UIAxesHourlyShadeVisibility, 'tight')
            axis(app.UIAxesHourlyShadeVisibility, 'equal')

            % Plot 3: Statistics
            valid_data = day_mask(hourly_data ~= -9999);
            h = pie([sum(valid_data == 1), sum(valid_data == 0)], 'Parent', app.UIAxesHourlyShadeDistribution);
            cmap = colormap(app.UIAxesHourlyShadeData);
            patchHand = findobj(h, 'Type', 'Patch');
    
            is_night = all(day_mask(hourly_data ~= -9999) == 0);
            if is_night
                patchHand(2).FaceColor = cmap(1,:);
                legend(app.UIAxesHourlyShadeDistribution, 'Shade', 'Location', 'northwest');
            else
                patchHand(1).FaceColor = cmap(end,:);
                patchHand(2).FaceColor = cmap(1,:);
                legend(app.UIAxesHourlyShadeDistribution, 'Sun visible', 'Shade', 'Location', 'northwest');
            end
            app.UIAxesHourlyShadeDistribution.Title.String = sprintf('Sun/Shade Distribution %s %d, Hour %d',...
                month_names{app.SelectMonthHourlyShade}, app.SelectDayHourlyShade, app.SelectHourHourlyShade);
            app.UIAxesHourlyShadeDistribution.Title.FontSize = 10;

            % ADD HOURLY SHADE ANIMATION
            % Plot 4: Hourly Shade Animation
            if app.RoofMaskHourly == true
               app.animateHourlyShade(shade_data, app.SelectDayHourlyShade, true)
               app.UIAxesHourlyShadeAnimation.Title.String = sprintf('Shade Data - %s, %d', ...
                    month_names{app.SelectMonthHourlyShade}, app.SelectDayHourlyShade);
               app.UIAxesHourlyShadeAnimation.Title.FontSize = 10;
            else
                app.animateHourlyShade(shade_data, app.SelectDayHourlyShade, false)
                app.UIAxesHourlyShadeAnimation.Title.String = sprintf('Shade Data - %s, %d', ...
                    month_names{app.SelectMonthHourlyShade}, app.SelectDayHourlyShade);
                app.UIAxesHourlyShadeAnimation.Title.FontSize = 10;
            end

            % Option: Plot 4: Hourly Shade - Invalid locations
            % Add UIAxes app.UIAxesHourlyShadeValidity
            % invalid_data = hourly_data == -9999;
            % imagesc(invalid_data, 'Parent', app.UIAxesHourlyShadeValidity);
            % colormap(app.UIAxesHourlyShadeValidity, [0 1 0; 1 0 0]); % Green for valid, red for invalid
            % colorbar(app.UIAxesHourlyShadeValidity,'Ticks', [0.25, 0.75], 'TickLabels', {'Valid', 'Invalid'});
            % app.UIAxesHourlyShadeValidity.Title.String = 'Data Validity Map';
            % app.UIAxesHourlyShadeValidity.Title.FontSize = 10;
            % axis(app.UIAxesHourlyShadeValidity, 'tight')
            % axis(app.UIAxesHourlyShadeValidity, 'equal')

            % Clean up
            delete(shade_filename);
        end
        
        function address = getAddress(app)
            try
                % Construct latitude and longitude
                latlong = [num2str(app.SelectedLatitude), ',', num2str(app.SelectedLongitude)];
                % Format the URL and append the API key
                reverseGeocodeURL = sprintf(app.GoogleMapsReverseGeocodeURL, latlong) + app.GM_API_KEY;
                
                % Fetch the response
                response = webread(reverseGeocodeURL, weboptions('CharacterEncoding', 'utf-8'));
                
                % Access the formatted_address field correctly
                if isfield(response, 'results') && ~isempty(response.results)
                    firstResult = response.results{1}; % Access the first cell of results
                    if isfield(firstResult, 'formatted_address')
                        address = string(firstResult.formatted_address); % Ensure it's a string
                    else
                        disp('No formatted_address field in the first result.');
                        address = 'No address found';
                    end
                else
                    disp('No results field in the response or results are empty.');
                    address = 'No address found';
                end
            catch ME
                % Handle errors
                disp(['Error fetching address: ', ME.message]);
                address = 'Error fetching address';
            end
        end
          
        function addTableDataBI(app,responseBI)
            % MAKE BI TABLES VISIBLE
            if strcmp(app.UITableBuildingInformation.Visible, 'off')
                app.UITableBuildingInformation.Visible = 'on';
            end
            if strcmp(app.UITableSolarPotential.Visible, 'off')
                app.UITableSolarPotential.Visible = 'on';
            end
            if strcmp(app.UITablePanelStats.Visible,'off')
                app.UITablePanelStats.Visible = 'on';
            end
            if strcmp(app.UITableRoofStats.Visible,'off')
                app.UITableRoofStats.Visible = 'on';
            end
            if strcmp(app.UITableImageryInfo.Visible, 'off')
                app.UITableImageryInfo.Visible = 'on';
            end


            % Building insights table
            % Location and date
            buildingInfoLat = responseBI.center.latitude;
            buildingInfoLng = responseBI.center.longitude;
            buildingInfoRegionCode = responseBI.regionCode;
            try
                buildingInfoAddress =  app.getAddress();
            catch
                buildingInfoAddress = "";
            end
            buildingInfoName = {"Latitude";"Longitude";"Region Code";"Address"};
            buildingInfoValue = {buildingInfoLat; buildingInfoLng; buildingInfoRegionCode; ...
                buildingInfoAddress};
            buildingInfoTable = table(buildingInfoName, buildingInfoValue);
            app.UITableBuildingInformation.Data = buildingInfoTable;
            app.UITableBuildingInformation.ColumnName = {'Name';'Value'};
            

            % SOLAR POTENTIAL TABLE
            % Extract metrics
            panelCapacityRatio  = app.SelectedPanelCapacity/app.DefaultPanelCapacity; 
            % 0 based indexing
            min_output = panelCapacityRatio*responseBI.solarPotential.solarPanelConfigs(1).yearlyEnergyDcKwh;
            min_panels = panelCapacityRatio*responseBI.solarPotential.solarPanelConfigs(1).panelsCount;
            max_panels = responseBI.solarPotential.maxArrayPanelsCount;
            max_output = panelCapacityRatio*responseBI.solarPotential.solarPanelConfigs(end).yearlyEnergyDcKwh;
            max_area = responseBI.solarPotential.maxArrayAreaMeters2;
            max_sunshine = responseBI.solarPotential.maxSunshineHoursPerYear;
            carbon_offset = responseBI.solarPotential.carbonOffsetFactorKgPerMwh;
            solarPotentialName = {"Max Array Panels Count"; "Max Output [kWh/Year] DC"; "Min Array Panels Count";...
                "Min Output [kWh/Year] DC"; "Max Array Area [m^2]"; "Max Sunshine [hours/year]"; ...
                "Carbon Offset Factor [kg/MWh]"};
            solarPotentialValue = {max_panels; max_output; min_panels; min_output; max_area; max_sunshine; carbon_offset};
            solarPotentialTable = table(solarPotentialName, solarPotentialValue);
            app.UITableSolarPotential.Data = solarPotentialTable;
            app.UITableSolarPotential.ColumnName = {'Name';'Value'};
            

            % PANEL STATS
            % Extract panel specs
            % panelStatsCapacity = responseBI.solarPotential.panelCapacityWatts;
            panelStatsCapacity = app.SelectedPanelCapacity;
            panelStatsLifetime = responseBI.solarPotential.panelLifetimeYears;
            panelStatsHeight = responseBI.solarPotential.panelHeightMeters;
            panelStatsWidth = responseBI.solarPotential.panelWidthMeters;
            panelStatsName = {"Capacity [W]"; "Lifetime [years]"; "Height [m]"; "Width [m]"};
            panelStatsValue = {panelStatsCapacity; panelStatsLifetime; panelStatsHeight; panelStatsWidth};
            panelStatsTable = table(panelStatsName, panelStatsValue);
            app.UITablePanelStats.Data = panelStatsTable;
            app.UITablePanelStats.ColumnName = {'Name';'Value'};
            
            % WHOLE ROOF STATISTICS
            % Extract whole roof stats
            wholeRoofArea = responseBI.solarPotential.wholeRoofStats.areaMeters2;
            groundArea = responseBI.solarPotential.wholeRoofStats.groundAreaMeters2;
            wholeRoofAreaName = {"Whole Roof Area [m^2]"; "Ground Area [m^2]"};
            wholeRoofAreaValue = {wholeRoofArea; groundArea};
            wholeRoofAreaTable = table(wholeRoofAreaName, wholeRoofAreaValue);
            app.UITableRoofStats.Data = wholeRoofAreaTable;
            app.UITableRoofStats.ColumnName = {'Name';'Value'};
            

            % IMAGERY INFORMATION
            imgDateBI = sprintf('%d-%02d-%02d', ...
                responseBI.imageryDate.year, ...
                responseBI.imageryDate.month, ...
                responseBI.imageryDate.day);
            imgQuality = responseBI.imageryQuality;
            dateImgProcessedBI = sprintf('%d-%02d-%02d', ...
                responseBI.imageryProcessedDate.year, ...
                responseBI.imageryProcessedDate.month, ...
                responseBI.imageryProcessedDate.day);
            imageryInfoTable = table({'Imagery Date'; 'Image Quality'; 'Processed Date'}, ...
                {imgDateBI; imgQuality; dateImgProcessedBI}, ...
                'VariableNames', {'Name', 'Value'});
            app.UITableImageryInfo.Data = imageryInfoTable;
            app.UITableImageryInfo.ColumnName = {'Name', 'Value'};
            
        end
             
        function addSolarPanelsMapsAndTables(app, responseBI)            
            % SOLAR PANELS
            if strcmp(app.UIAxesPanelConfigsProductionPerPanelBI.Visible, 'off')
                app.UIAxesPanelConfigsProductionPerPanelBI.Visible = 'on';
            end
            if strcmp(app.UIAxesPanelConfigsPanelsPerSegmentBI.Visible, 'off')
                app.UIAxesPanelConfigsPanelsPerSegmentBI.Visible = 'on';
            end
            if strcmp(app.UIAxesPanelConfigsEnergyPerPanelHistBI.Visible, 'off')
                app.UIAxesPanelConfigsEnergyPerPanelHistBI.Visible = 'on';
            end
            if strcmp(app.UITableIndividualPanelsStatsBI.Visible, 'off')
                app.UITableIndividualPanelsStatsBI.Visible = 'on';
            end
            if strcmp(app.OptimiseButtonGroup.Visible, 'off')
                app.OptimiseButtonGroup.Visible = 'on';
            end
            if strcmp(app.PanelLocationsandEnergyProductionkWhYear.Visible,'off')
                app.PanelLocationsandEnergyProductionkWhYear.Visible = 'on';
            end
            if strcmp(app.RoofSegmentLocationsPanel.Visible, 'off')
                app.RoofSegmentLocationsPanel.Visible = 'on';
            end
            if strcmp(app.UITableIndividualPanelsBI.Visible, 'off')
                app.UITableIndividualPanelsBI.Visible = 'on';
            end
            if strcmp(app.UITableSelectedArray.Visible, 'off')
                app.UITableSelectedArray.Visible = 'on';
            end
            if strcmp(app.MaxArrayIndividualPanelStatistics.Visible, 'off')
                app.MaxArrayIndividualPanelStatistics.Visible = 'on';
            end
            if strcmp(app.SelectedArrayIndividualPanelsStatistics.Visible, 'off')
                app.SelectedArrayIndividualPanelsStatistics.Visible = 'on';
            end
            if strcmp(app.IndividualPanelsMaxArray.Visible, 'off')
                app.IndividualPanelsMaxArray.Visible = 'on';
            end
            if strcmp(app.IndividualPanelsSelectedArray.Visible, 'off')
                app.IndividualPanelsSelectedArray.Visible = 'on';
            end
            % ROOF SEGMENTS
            if strcmp(app.NumberofSegmentsbyIndexSlider.Visible, 'off')
                app.NumberofSegmentsbyIndexSlider.Visible = 'on';
            end
            if strcmp(app.SegmentIndexEditField.Visible, 'off')
                app.SegmentIndexEditField.Visible = 'on';
            end
            if strcmp(app.SegmentIndexEnergyProductionkWhyearEditField.Visible, 'off')
                app.SegmentIndexEnergyProductionkWhyearEditField.Visible = 'on';
            end
            if strcmp(app.SegmentIndexPercentofMaxArrayEditField.Visible, 'off')
                app.SegmentIndexPercentofMaxArrayEditField.Visible = 'on';
            end
            if strcmp(app.SegmentIndexRefreshButton.Visible, 'off')
                app.SegmentIndexRefreshButton.Visible = 'on';
            end
            if strcmp(app.UIAxesSelectedPanelsHist.Visible, 'off')
                app.UIAxesSelectedPanelsHist.Visible = 'on';
            end
             
            % SINGLE PANEL LOCATIONS
            % ----------------------
            
            % GET SOLAR PANEL DATA
            % --------------------
            panels = responseBI.solarPotential.solarPanels;
            % MAX ARRAY
            n_panels = length(panels);
            % --------------------

            if n_panels == 0
                error('No panel data found in the response');
            end

            if n_panels < 250
                plotMarkerSize = 30;
            elseif n_panels >= 250 && n_panels < 450
                plotMarkerSize = 20;
            else
                plotMarkerSize = 10;
            end
               
            % Extract panel locations and energy data
            latitudesPanels = zeros(1, n_panels);
            longitudesPanels = zeros(1, n_panels);
            energiesPanels = zeros(1, n_panels);
            segmentsPanels = zeros(1, n_panels);
            panelNumberPanels = zeros(1, n_panels);
            orientationPanels = strings(1, n_panels);
            pitchPanels = zeros(1,n_panels);
            azimuthPanels = zeros(1,n_panels);

            panelCapacityRatio = app.SelectedPanelCapacity/app.DefaultPanelCapacity;
            
            for i = 1:n_panels
                latitudesPanels(i) = panels(i).center.latitude;
                longitudesPanels(i) = panels(i).center.longitude;
                % Assume linear scaling of energy production by panel
                % capacity
                energiesPanels(i) = panelCapacityRatio*panels(i).yearlyEnergyDcKwh;
                segmentsPanels(i) = panels(i).segmentIndex;
                % Get pitch and azimuth from roof segment data, 0-based
                % indexing
                pitchPanels(i) = responseBI.solarPotential.roofSegmentStats(segmentsPanels(i)+1).pitchDegrees;
                azimuthPanels(i) = responseBI.solarPotential.roofSegmentStats(segmentsPanels(i)+1).azimuthDegrees;
                panelNumberPanels(i) = i;
                orientationPanels(i) = panels(i).orientation;
            end

            % OPTIMISE
            % app.OptimiseButtonGroup
            if strcmp(app.OptimiseSelection, 'Yes')
                % Optimisation enabled: select top n panels
                [~, sortedIndices] = sort(energiesPanels, 'descend');
                TopN = sortedIndices(1:app.NumberOfPanels);
                BottomM = sortedIndices(app.NumberOfPanels+1:end);
            else
                % Optimisation disabled: use panels in their original order
                TopN = 1:app.NumberOfPanels;
                BottomM = app.NumberOfPanels+1:numel(energiesPanels);
            end

            % Increase precision for latitudes and longitudes
            formattedLatitudes = arrayfun(@(x) sprintf('%.8f', x), latitudesPanels, 'UniformOutput', false);
            formattedLongitudes = arrayfun(@(x) sprintf('%.8f', x), longitudesPanels, 'UniformOutput', false);

            % INDIVIDUAL PANELS TABLE - SELECTED PANEL ARRAY
            % Create a table from the panel data
            selectedPanelsTable = table(...
                (panelNumberPanels(TopN))', ... % Panel #
                segmentsPanels(TopN)', ...      % Segment
                formattedLatitudes(TopN)', ...     % Latitude
                formattedLongitudes(TopN)', ...    % Longitude
                energiesPanels(TopN)', ...      % Energy (kWh/year)
                pitchPanels(TopN)', ... % Pitch (°)
                azimuthPanels(TopN)', ... % Azimuth (°)
                orientationPanels(TopN)' ...   % Orientation
                );
            app.UITableSelectedArray.ColumnName = {'#', 'Segment', ...
                'Latitude', 'Longitude', 'Energy [kWh]', 'Pitch (°)', 'Azimuth (°)', 'Orientation'};
            app.UITableSelectedArray.Data = selectedPanelsTable;

            % LOCATION MAP OF SINGLE PANELS
            % -----------------------------
            % Create scatter plot
            cla(app.SinglePanelsLocations);
            % if not set in startupfcn
            % geobasemap(app.SinglePanelsLocations,'satellite')
            geoplot(app.SinglePanelsLocations,longitudesPanels(TopN), latitudesPanels(TopN), ...
                'o', 'MarkerFaceColor', 'r', 'MarkerSize', 6);
            hold(app.SinglePanelsLocations,'on')
            geoscatter(app.SinglePanelsLocations,latitudesPanels(TopN), ...
                longitudesPanels(TopN), plotMarkerSize, energiesPanels(TopN), 'filled');
            app.SinglePanelsLocations.LatitudeAxis.Visible = 'off';  
            app.SinglePanelsLocations.LongitudeAxis.Visible = 'off'; 
            hold(app.SinglePanelsLocations,'off')

            % Adjust map limits to focus on the data
            latMin = min(latitudesPanels);
            latMax = max(latitudesPanels);
            lonMin = min(longitudesPanels);
            lonMax = max(longitudesPanels);
            margin = 0.0003; % Adjust as needed
            geolimits(app.SinglePanelsLocations, ...
                [latMin - margin, latMax + margin], ...
                [lonMin - margin, lonMax + margin]);

            c = colorbar(app.SinglePanelsLocations);
            c.Label.String = 'Energy (kWh/year)';
            colormap(app.SinglePanelsLocations,'jet');
            title(app.SinglePanelsLocations, 'Solar Panel Locations and Energy Production');

            % Add selected panels statistics     
            maxOutput = panelCapacityRatio*responseBI.solarPotential.solarPanelConfigs(end).yearlyEnergyDcKwh;
            % Energies have already panelCapacityRatio factored in
            selectedPanelsOutput = sum(energiesPanels(TopN));
            PctOfMaxArrayOutput = round((selectedPanelsOutput/maxOutput)*100, 2);

            app.EnergyProductionkWhyearEditField.Value = selectedPanelsOutput;
            app.PercentofMaxArrayEditField.Value = PctOfMaxArrayOutput;

            % Add stats (selected panels)
            % Calculate max, min, range, mean, median, std etc.
            count_energy_sel = round(app.NumberOfPanels,0);
            sum_energy_sel = sum(energiesPanels(TopN));
            max_energy_sel = max(energiesPanels(TopN));
            min_energy_sel = min(energiesPanels(TopN));
            range_energy_sel = range(energiesPanels(TopN));
            mean_energy_sel = mean(energiesPanels(TopN));
            median_energy_sel = median(energiesPanels(TopN));
            std_energy_sel = std(energiesPanels(TopN));
            var_energy_sel = var(energiesPanels(TopN));
            cv_energy_sel = std_energy_sel/mean_energy_sel;
            iqr_energy_sel = iqr(energiesPanels(TopN));
            skew_energy_sel = skewness(energiesPanels(TopN));
            kurt_energy_sel = kurtosis(energiesPanels(TopN));

            % ADD SELECTED PANELS DATA TABLE
            % ------------------------------
            % app.SelectedPanelArrayTab

            % Add stats into a table, app.UITableIndividualPanelsStatsBI
            statsNamesSel = {'Count (# Panels)'; 'Sum Total Output (kWh/year)';'Maximum Energy (kWh/year)'; 'Minimum Energy (kWh/year)'; 'Range of Energy (kWh/year)'; ...
                          'Mean Energy (kWh/year)'; 'Median Energy (kWh/year)'; 'Std of Energy (kWh/year)';...
                          'Variance of energy (kWh/year)²';'Coefficient of Variation';'IQR (kWh/year)'; 'Skewness of Energy';...
                          'Kurtosis'};
            statsValuesSel = [count_energy_sel; sum_energy_sel;max_energy_sel; min_energy_sel; range_energy_sel; ...
                           mean_energy_sel; median_energy_sel; std_energy_sel; var_energy_sel;cv_energy_sel; iqr_energy_sel;...
                           skew_energy_sel; kurt_energy_sel];
            statsTable = table(statsNamesSel, statsValuesSel);
            app.UITableSelectedPanels.Data = statsTable;
            app.UITableSelectedPanels.ColumnName = {'Name', 'Value'};

            % HISTOGRAM OF SELECTED PANEL ARRAY
            % ------------------------
            % app.SelectedPanelArrayTab
            cla(app.UIAxesSelectedPanelsHist, 'reset');
            histogram(app.UIAxesSelectedPanelsHist, energiesPanels(TopN), 'BinMethod', 'integers');
            hold(app.UIAxesSelectedPanelsHist, 'on'); 
            % mean_energy = mean(energiesPanels(TopN)); % Calculate the mean energy
            plot(app.UIAxesSelectedPanelsHist, [mean_energy_sel, mean_energy_sel],...
                [0, max(histcounts(energiesPanels(TopN), 'BinMethod', 'integers'))], 'r--', 'LineWidth', 2); % Plot the mean energy line
            hold(app.UIAxesSelectedPanelsHist, 'off')
            app.UIAxesSelectedPanelsHist.Title.String = 'Selected Array Energy Distribution Per Panel (kWh/year)';
            app.UIAxesSelectedPanelsHist.Title.FontSize = 10;
            app.UIAxesSelectedPanelsHist.XLabel.String = 'Energy (kWh/year) DC';
            app.UIAxesSelectedPanelsHist.YLabel.String = 'Frequency';

            % Set x-axis ticks at min, mean, and max energy values
            app.UIAxesSelectedPanelsHist.XTick = [min_energy_sel, mean_energy_sel, max_energy_sel];
            app.UIAxesSelectedPanelsHist.XTickLabel = {sprintf('%.2f', min_energy_sel), ...
                                                         sprintf('%.2f', mean_energy_sel), ...
                                                         sprintf('%.2f', max_energy_sel)};
            % Adjust x-axis limits for better visualization
            app.UIAxesSelectedPanelsHist.XLim = [min_energy_sel - 1, max_energy_sel + 1];

            % Define statistics text box
            statsTextSel = sprintf('Sum Total Output: %.2f kWh/year\n', ...
                round(sum_energy_sel,0));
            
            % Add the text box to the bottom-left corner of the plot
            xLimitsSel = app.UIAxesSelectedPanelsHist.XLim;
            yLimitsSel = app.UIAxesSelectedPanelsHist.YLim;
            
            xPosSel = xLimitsSel(1) + 0.05 * (xLimitsSel(2) - xLimitsSel(1)); % Slightly offset from the left edge
            yPosSel = yLimitsSel(2) - 0.10* (yLimitsSel(2) - yLimitsSel(1)); % Slightly offset from the top edge
            
            text(app.UIAxesSelectedPanelsHist, xPosSel, yPosSel, statsTextSel, ...
                'FontSize', 9, 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'left', ...
                'BackgroundColor', 'w', 'EdgeColor', 'k', 'Margin', 5);
            
            % ROOF SEGMENTS
            
            % Select the indices
            % selectedSegmentIndices = segmentsPanels <= app.SegmentNumber;
            selectedSegmentIndices = find(segmentsPanels <= app.SegmentNumber);

            % Add selected panels statistics     
            % maxOutput = panelCapacityRatio*responseBI.solarPotential.solarPanelConfigs(end).yearlyEnergyDcKwh;
            % Energies have already panelCapacityRatio factored in
            SelectedSegmentsOutput = sum(energiesPanels(selectedSegmentIndices));
            SegmentsPctOfMaxArrayOutput = round((SelectedSegmentsOutput/maxOutput)*100, 2);

            app.SegmentIndexEnergyProductionkWhyearEditField.Value = SelectedSegmentsOutput;
            app.SegmentIndexPercentofMaxArrayEditField.Value = SegmentsPctOfMaxArrayOutput;


            % ROOF SEGMENT LOCATIONS   
            % ----------------------
            cla(app.RoofSegmentLocations); 
            % geobasemap(app.RoofSegmentLocations,'satellite');
            % Create scatter plot
            geoplot(app.RoofSegmentLocations,longitudesPanels(selectedSegmentIndices), latitudesPanels(selectedSegmentIndices), ...
                'o', 'MarkerFaceColor', 'r', 'MarkerSize', 6);
            hold(app.RoofSegmentLocations,'on')
            geoscatter(app.RoofSegmentLocations,latitudesPanels(selectedSegmentIndices), longitudesPanels(selectedSegmentIndices), ... 
                plotMarkerSize, segmentsPanels(selectedSegmentIndices), 'filled');
            hold(app.RoofSegmentLocations,'off')

            % Adjust map limits to focus on the data
            latMin = min(latitudesPanels);
            latMax = max(latitudesPanels);
            lonMin = min(longitudesPanels);
            lonMax = max(longitudesPanels);
            margin = 0.0003; % Adjust as needed
            geolimits(app.RoofSegmentLocations, ...
                [latMin - margin, latMax + margin], ...
                [lonMin - margin, lonMax + margin]);

            c = colorbar(app.RoofSegmentLocations);
            c.Label.String = 'Segment index';
            colormap(app.RoofSegmentLocations,'jet');
            % Option 1: Remove tick marks but keep labels
            % unique_segments = unique(segmentsPanels);
            % c.Ticks = [];
            % c.TickLabels = unique_segments;

            % Option 2: Show sparse ticks (e.g., every 2 segments)
            unique_segments = unique(segmentsPanels(selectedSegmentIndices));
            sparse_ticks = unique_segments(1:2:end); % Adjust the step as needed
            c.Ticks = sparse_ticks; % Set sparse ticks
            c.TickLabels = string(sparse_ticks); % Label sparse ticks

            % Adjust color limits to align with discrete segments
            % Set title
            title(app.RoofSegmentLocations, 'Roof segment locations');
            app.RoofSegmentLocations.LatitudeAxis.Visible = 'off';  
            app.RoofSegmentLocations.LongitudeAxis.Visible = 'off'; 

            % ENERGY PRODUCTION PER PANEL
            % ---------------------------
            
            % scatter plot
            cla(app.UIAxesPanelConfigsProductionPerPanelBI, 'reset')
            scatter(app.UIAxesPanelConfigsProductionPerPanelBI, panelNumberPanels(TopN), ...
                energiesPanels(TopN), 'MarkerEdgeColor', 'blue');
            hold(app.UIAxesPanelConfigsProductionPerPanelBI, 'on'); 
            scatter(app.UIAxesPanelConfigsProductionPerPanelBI, panelNumberPanels(BottomM), ...
                energiesPanels(BottomM), 'MarkerEdgeColor', [0.5843 0.8157 0.9882]);
            average_energy = mean(energiesPanels);
            plot(app.UIAxesPanelConfigsProductionPerPanelBI, [min(panelNumberPanels), ...
                max(panelNumberPanels)], [average_energy, average_energy], 'r--', 'LineWidth', 2); 
            hold(app.UIAxesPanelConfigsProductionPerPanelBI, 'off');
            app.UIAxesPanelConfigsProductionPerPanelBI.Title.String = ['Yearly Energy Production Per' ...
                ' Panel (kWh/year DC)'];
            app.UIAxesPanelConfigsProductionPerPanelBI.Title.FontSize = 10;
            app.UIAxesPanelConfigsProductionPerPanelBI.XLabel.String = 'Panel number';
            app.UIAxesPanelConfigsProductionPerPanelBI.YLabel.String = 'Yearly energy (kWh/year DC)';

            % Add stats
            % Calculate max, min, range, mean, median, std etc.
            count_energy = n_panels;
            sum_energy = sum(energiesPanels);
            max_energy = max(energiesPanels);
            min_energy = min(energiesPanels);
            range_energy = range(energiesPanels);
            mean_energy = mean(energiesPanels);
            median_energy = median(energiesPanels);
            std_energy = std(energiesPanels);
            var_energy = var(energiesPanels);
            cv_energy = std_energy/mean_energy;
            iqr_energy = iqr(energiesPanels);
            skew_energy = skewness(energiesPanels);
            kurt_energy = kurtosis(energiesPanels);

            % Define statistics text box
            statsText = sprintf('Max Array:\nMax: %.2f kWh/year\nMin: %.2f kWh/year\nRange: %.2f kWh/year\nMean: %.2f kWh/year\nMedian: %.2f kWh/year\nStd: %.2f kWh/year', ...
                max_energy, min_energy, range_energy, mean_energy, median_energy, std_energy);
            
            % Add the text box to the bottom-left corner of the plot
            xLimits = app.UIAxesPanelConfigsProductionPerPanelBI.XLim;
            yLimits = app.UIAxesPanelConfigsProductionPerPanelBI.YLim;
            
            xPos = xLimits(1) + 0.05 * (xLimits(2) - xLimits(1)); % Slightly offset from the left edge
            yPos = yLimits(1) + 0.05 * (yLimits(2) - yLimits(1)); % Slightly offset from the bottom edge
            
            text(app.UIAxesPanelConfigsProductionPerPanelBI, xPos, yPos, statsText, ...
                'FontSize', 9, 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'left', ...
                'BackgroundColor', 'w', 'EdgeColor', 'k', 'Margin', 5);


            % INDIVIDUAL PANEL STATISTICS TABLE
            % ---------------------------------
            % Add stats into a table, app.UITableIndividualPanelsStatsBI
            % Define the names and corresponding values for the stats
            statsNames = {'Count (# Panels)'; 'Sum Total Output (kWh/year)';'Maximum Energy (kWh/year)'; 'Minimum Energy (kWh/year)'; 'Range of Energy (kWh/year)'; ...
                          'Mean Energy (kWh/year)'; 'Median Energy (kWh/year)'; 'Std of Energy (kWh/year)';...
                          'Variance of energy (kWh/year)²';'Coefficient of Variation';'IQR (kWh/year)'; 'Skewness of Energy';...
                          'Kurtosis'};
            statsValues = [count_energy; sum_energy; max_energy; min_energy; range_energy; ...
                           mean_energy; median_energy; std_energy; var_energy;cv_energy; iqr_energy;...
                           skew_energy; kurt_energy];
            statsTable = table(statsNames, statsValues);
            app.UITableIndividualPanelsStatsBI.Data = statsTable;
            app.UITableIndividualPanelsStatsBI.ColumnName = {'Name', 'Value'};

            % HISTOGRAM OF MAX ARRAY
            % ----------------------
            cla(app.UIAxesPanelConfigsEnergyPerPanelHistBI, 'reset')
            histogram(app.UIAxesPanelConfigsEnergyPerPanelHistBI, energiesPanels, 'BinMethod', 'integers');
            hold(app.UIAxesPanelConfigsEnergyPerPanelHistBI, 'on'); 
            mean_energy = mean(energiesPanels); % Calculate the mean energy
            plot(app.UIAxesPanelConfigsEnergyPerPanelHistBI, [mean_energy, mean_energy],...
                [0, max(histcounts(energiesPanels, 'BinMethod', 'integers'))], 'r--','LineWidth', 2); % Plot the mean energy line
            hold(app.UIAxesPanelConfigsEnergyPerPanelHistBI, 'off')
            app.UIAxesPanelConfigsEnergyPerPanelHistBI.Title.String = 'Max Array Energy Distribution Per Panel (kWh/year)';
            app.UIAxesPanelConfigsEnergyPerPanelHistBI.Title.FontSize = 10;
            app.UIAxesPanelConfigsEnergyPerPanelHistBI.XLabel.String = 'Energy (kWh/year) DC';
            app.UIAxesPanelConfigsEnergyPerPanelHistBI.YLabel.String = 'Frequency';

            % Set x-axis ticks at min, mean, and max energy values
            app.UIAxesPanelConfigsEnergyPerPanelHistBI.XTick = [min_energy, mean_energy, max_energy];
            app.UIAxesPanelConfigsEnergyPerPanelHistBI.XTickLabel = {sprintf('%.2f', min_energy), ...
                                                         sprintf('%.2f', mean_energy), ...
                                                         sprintf('%.2f', max_energy)};
            % Adjust x-axis limits for better visualization
            app.UIAxesPanelConfigsEnergyPerPanelHistBI.XLim = [min_energy - 1, max_energy + 1];
            
            % SINGLE SOLAR PANELS TABLE MAX ARRAY
            % -----------------------------------
            % Create a table from the panel data
            individualPanelsTable = table(...
                (1:n_panels)', ... % Panel #
                segmentsPanels', ...      % Segment
                formattedLatitudes', ...     % Latitude
                formattedLongitudes', ...    % Longitude
                energiesPanels', ...      % Energy (kWh/year)
                pitchPanels', ... % Pitch (°)
                azimuthPanels', ... % Azimuth (°)
                orientationPanels' ...   % Orientation
                );
            app.UITableIndividualPanelsBI.ColumnName = {'#', 'Segment', ...
                'Latitude', 'Longitude', 'Energy [kWh]', 'Pitch (°)', 'Azimuth (°)','Orientation'};
            app.UITableIndividualPanelsBI.Data = individualPanelsTable;

            % PANELS PER SEGMENT
            % ------------------
            % Histogram plot
            cla(app.UIAxesPanelConfigsPanelsPerSegmentBI, 'reset')
            histogram(app.UIAxesPanelConfigsPanelsPerSegmentBI, segmentsPanels, ...
                'BinMethod', 'integers', 'BinLimits', [0, max(segmentsPanels)]);
            
            % Set title and labels
            app.UIAxesPanelConfigsPanelsPerSegmentBI.Title.String = 'Max Array Number of Panels per Roof Segment';
            app.UIAxesPanelConfigsPanelsPerSegmentBI.Title.FontSize = 10;
            app.UIAxesPanelConfigsPanelsPerSegmentBI.XLabel.String = 'Segment Index';
            app.UIAxesPanelConfigsPanelsPerSegmentBI.YLabel.String = 'Number of Panels';

            % Retrieve the histogram object to access bin information
            histObj = findobj(app.UIAxesPanelConfigsPanelsPerSegmentBI, 'Type', 'Histogram');
            
            % Calculate bin centers
            binEdges = histObj.BinEdges; % Edges of the bins
            binCenters = binEdges(1:end-1) + diff(binEdges) / 2; % Center of each bin
            
            % Set x-axis ticks and labels
            app.UIAxesPanelConfigsPanelsPerSegmentBI.XTick = binCenters; % Set tick positions at bin centers

            % Handle XLim edge case when binEdges has identical values
            if binEdges(1) == binEdges(end)
                % Extend the range slightly to ensure a valid X-axis range
                app.UIAxesPanelConfigsPanelsPerSegmentBI.XLim = [binEdges(1) - 0.5, binEdges(1) + 0.5];
            else
                % Use the normal range
                app.UIAxesPanelConfigsPanelsPerSegmentBI.XLim = [binEdges(1), binEdges(end)];
            end

            % Adjust x-axis ticks dynamically
            maxSegments = max(segmentsPanels); % Determine the maximum segment index
            if maxSegments <= 10
                % Show all ticks if there are 10 or fewer segments
                app.UIAxesPanelConfigsPanelsPerSegmentBI.XTick = 1:maxSegments;
            else
                % Dynamically reduce the number of ticks
                step = ceil(maxSegments / 10); % Ensure no more than 10 ticks
                app.UIAxesPanelConfigsPanelsPerSegmentBI.XTick = 1:step:maxSegments;
            end

            % Get the frequencies of each bin
            binFrequencies = histObj.Values; % Frequencies (counts) for each bin
            
            % Find the maximum frequency
            maxFrequency = max(binFrequencies);

            % Set y-axis limits explicitly
            app.UIAxesPanelConfigsPanelsPerSegmentBI.YLim = [0, maxFrequency + 1]; % Add a margin of 1 for aesthetics
                        
            % Set y-axis ticks dynamically based on the maximum frequency
            if maxFrequency <= 10
                % Show all ticks if the maximum frequency is 10 or less
                app.UIAxesPanelConfigsPanelsPerSegmentBI.YTick = 0:maxFrequency;
            else
                % Dynamically reduce the number of ticks
                step = ceil(maxFrequency / 10); % Ensure no more than 10 ticks
                app.UIAxesPanelConfigsPanelsPerSegmentBI.YTick = 0:step:maxFrequency;
            end
    
 
        end
              
        function addQuantilesPlot(app, responseBI)            
            
            % SUNSHINE QUANTILES
            if strcmp(app.UIAxesSunshineQuantilesBI.Visible, 'off')
                app.UIAxesSunshineQuantilesBI.Visible = 'on';
            end
            if strcmp(app.UITableSunshineQuantilePctBI.Visible, 'off')
                app.UITableSunshineQuantilePctBI.Visible = 'on';
            end

            % Extract segment stats
            quantiles = responseBI.solarPotential.wholeRoofStats.sunshineQuantiles;

            cla(app.UIAxesSunshineQuantilesBI, 'reset');
            bar(app.UIAxesSunshineQuantilesBI,1:11,quantiles);
            hold(app.UIAxesSunshineQuantilesBI, 'on')
            average_sunshine = mean(quantiles); 
            plot(app.UIAxesSunshineQuantilesBI,[min(1:11), max(1:11)],...
                [average_sunshine, average_sunshine], 'r--', 'LineWidth', 2);

            hold(app.UIAxesSunshineQuantilesBI, 'off')
            app.UIAxesSunshineQuantilesBI.Title.String = 'Roof Sunshine Distribution';
            app.UIAxesSunshineQuantilesBI.Title.FontSize = 10;
            app.UIAxesSunshineQuantilesBI.XLabel.String = 'Quantile';
            app.UIAxesSunshineQuantilesBI.YLabel.String = 'Sunshine (kWh/kW/year)';
            
            % PCT CONTRIBUTIONS OF SUNSHINE QUANTILES
            % Prepare data for the table
            quantile_indices = (1:11)'; % Quantile indices as a column vector (11 rows)
            percent_contributions = (quantiles / sum(quantiles)) * 100; % Percentage contributions as a column vector
            % Ensure all variables are column vectors with the same size
            quantiles_column = quantiles(:); % Convert to column vector if not already
            data_pct = table(quantile_indices, quantiles_column, percent_contributions, ...
                'VariableNames', {'Quantile', 'Sunshine', 'PercentageContribution'});
            app.UITableSunshineQuantilePctBI.Data = data_pct;
            % Optionally set column names explicitly for display
            app.UITableSunshineQuantilePctBI.ColumnName = {'Quantile', 'Sunshine (kWh/kW/year)', 'Contribution (%)'};


        end
        

        function addRoofSegmentsPlotAndTable(app, responseBI)

            % app.UIAxesRoofSegmentAreaHeightBI app.UIAxesRoofSegmentOrientationsBI
            if strcmp(app.UIAxesRoofSegmentAreaHeightBI.Visible, 'off')
                app.UIAxesRoofSegmentAreaHeightBI.Visible = 'on';
            end
            if strcmp(app.UIAxesRoofSegmentOrientationsBI.Visible, 'off')
                app.UIAxesRoofSegmentOrientationsBI.Visible = 'on';
            end
            if strcmp(app.UITableRoofSegmentInfo.Visible, 'off')
                app.UITableRoofSegmentInfo.Visible = 'on';
            end

            n_segments = length(responseBI.solarPotential.roofSegmentStats);

            segmentsPitch = zeros(1, n_segments);
            segmentsAzimuth = zeros(1, n_segments);
            segmentsArea = zeros(1, n_segments);
            segmentsHeight = zeros(1, n_segments);

            for i = 1:n_segments
                segment = responseBI.solarPotential.roofSegmentStats(i);
                segmentsPitch(i) = segment.pitchDegrees;
                segmentsAzimuth(i) = segment.azimuthDegrees;
                segmentsArea(i) = segment.stats.areaMeters2;
                segmentsHeight(i) = segment.planeHeightAtCenterMeters;
            end

            % mean_area = mean(segmentsArea);
            % mean_height = mean(segmentsHeight);

            % SEGMENT AREA & HEIGHT PLOT
            % -----------------
            
            cla(app.UIAxesRoofSegmentAreaHeightBI, 'reset'); 
            % Plot Area on the left y-axis
            yyaxis(app.UIAxesRoofSegmentAreaHeightBI, "left");
            plot(app.UIAxesRoofSegmentAreaHeightBI, 0:n_segments-1, segmentsArea, '-', ...
                'LineWidth', 2, 'Color', 'b');
            app.UIAxesRoofSegmentAreaHeightBI.YLabel.String = 'Area (m²)';
            app.UIAxesRoofSegmentAreaHeightBI.YColor = 'b'; % Set left y-axis color to match plot
            % Set limits for left y-axis based on segmentsArea data
            app.UIAxesRoofSegmentAreaHeightBI.YLim = [0, max(segmentsArea) * 1.1]; % Add margin
            % Plot Height on the right y-axis
            yyaxis(app.UIAxesRoofSegmentAreaHeightBI, "right");
            plot(app.UIAxesRoofSegmentAreaHeightBI, 0:n_segments-1, segmentsHeight, '-', ...
                'LineWidth', 2, 'Color', 'r');
            app.UIAxesRoofSegmentAreaHeightBI.YLabel.String = 'Height (m)';
            app.UIAxesRoofSegmentAreaHeightBI.YColor = 'r'; % Set right y-axis color to match plot
            % Set limits for right y-axis based on segmentsHeight data
            app.UIAxesRoofSegmentAreaHeightBI.YLim = [0, max(segmentsHeight) * 1.1]; % Add margin
            % Set x-axis label
            app.UIAxesRoofSegmentAreaHeightBI.XLabel.String = 'Segment Index';
            if n_segments <= 10
                app.UIAxesRoofSegmentAreaHeightBI.XTick = 0:n_segments-1; % Ensure integer x-ticks
            else
                step = ceil(n_segments / 10);
                app.UIAxesRoofSegmentAreaHeightBI.XTick = 0:step:n_segments-1;
            end
            app.UIAxesRoofSegmentAreaHeightBI.XLim = [0.5, n_segments + 0.5]; % Add a small margin
            app.UIAxesRoofSegmentAreaHeightBI.Title.String = 'Roof Segment Areas (m²) and Plane Heights At Center (m)';
            app.UIAxesRoofSegmentAreaHeightBI.Title.FontSize = 10;
            legend(app.UIAxesRoofSegmentAreaHeightBI, "Area", "Height");    

            % SEGMENT PANEL ORIENTATIONS PLOT
            % -------------------------------

            % cla(app.UIAxesRoofSegmentOrientationsBI, 'reset'); % Completely reset the axes, including properties
            % plot(app.UIAxesRoofSegmentOrientationsBI, 1:n_segments, segmentsPitch, '-', 'LineWidth', 2, 'Color', 'b');
            % yyaxis(app.UIAxesRoofSegmentOrientationsBI, "left");
            % app.UIAxesRoofSegmentOrientationsBI.XLabel.String = 'Segment Number';
            % app.UIAxesRoofSegmentOrientationsBI.YLabel.String = 'Pitch (°)';
            % hold(app.UIAxesRoofSegmentOrientationsBI, "on");
            % plot(app.UIAxesRoofSegmentOrientationsBI, 1:n_segments, segmentsAzimuth, '-', 'LineWidth', 2, 'Color', 'r');
            % yyaxis(app.UIAxesRoofSegmentOrientationsBI, "right");
            % app.UIAxesRoofSegmentOrientationsBI.YLabel.String = 'Azimuth (°)';
            % legend(app.UIAxesRoofSegmentOrientationsBI, "Pitch", "Azimuth");
            % app.UIAxesRoofSegmentOrientationsBI.Title.String = 'Roof Segment Panel Orientations (°)';
            % app.UIAxesRoofSegmentOrientationsBI.Title.FontSize = 10;
            % hold(app.UIAxesRoofSegmentOrientationsBI, "off");

            cla(app.UIAxesRoofSegmentOrientationsBI, 'reset'); % Completely reset the axes, including properties
            % Plot Pitch on the left y-axis
            yyaxis(app.UIAxesRoofSegmentOrientationsBI, "left");
            plot(app.UIAxesRoofSegmentOrientationsBI, 0:n_segments-1, segmentsPitch, '-', ...
                'LineWidth', 2, 'Color', 'b');
            app.UIAxesRoofSegmentOrientationsBI.YLabel.String = 'Pitch (°)';
            app.UIAxesRoofSegmentOrientationsBI.YColor = 'b'; % Set left y-axis color to match plot
            % Set limits for left y-axis based on segmentsPitch data
            app.UIAxesRoofSegmentOrientationsBI.YLim = [0, max(segmentsPitch) * 1.1]; % Add margin
            % Plot Azimuth on the right y-axis
            yyaxis(app.UIAxesRoofSegmentOrientationsBI, "right");
            plot(app.UIAxesRoofSegmentOrientationsBI, 0:n_segments-1, segmentsAzimuth, '-', ...
                'LineWidth', 2, 'Color', 'r');
            app.UIAxesRoofSegmentOrientationsBI.YLabel.String = 'Azimuth (°)';
            app.UIAxesRoofSegmentOrientationsBI.YColor = 'r'; % Set right y-axis color to match plot
            % Set limits for right y-axis based on segmentsAzimuth data
            app.UIAxesRoofSegmentOrientationsBI.YLim = [min(segmentsAzimuth) - 10, max(segmentsAzimuth) + 10]; % Add margin
            % Set x-axis label
            app.UIAxesRoofSegmentOrientationsBI.XLabel.String = 'Segment Index';
            if n_segments <= 10
                app.UIAxesRoofSegmentOrientationsBI.XTick = 0:n_segments-1; % Ensure integer x-ticks
            else
                step = ceil(n_segments / 10);
                app.UIAxesRoofSegmentOrientationsBI.XTick = 0:step:n_segments-1;
            end
            app.UIAxesRoofSegmentOrientationsBI.Title.String = 'Roof Segment Panel Orientations (°)';
            app.UIAxesRoofSegmentOrientationsBI.Title.FontSize = 10;
            legend(app.UIAxesRoofSegmentOrientationsBI, "Pitch", "Azimuth");


            % SEGMENT DATA TABLE
            % ------------------

            segmentNumbers = (0:n_segments-1)'; % Segment numbers
            roofSegmentTable = table(segmentNumbers, segmentsPitch', segmentsAzimuth', segmentsArea', ...
                'VariableNames', {'Segment', 'Pitch', 'Azimuth', 'Area'});
            app.UITableRoofSegmentInfo.Data = roofSegmentTable;
            app.UITableRoofSegmentInfo.ColumnName = {'Segment', 'Pitch [°]', 'Azimuth [°]', 'Area [m^2]'};
            if strcmp(app.UITableRoofSegmentInfo.Visible, 'off')
                app.UITableRoofSegmentInfo.Visible = 'on';
            end

            
        end
        
        function addSolarPanelConfigsPlot(app, responseBI)

            % YEARLY ENERGY PRODUCTION BY CONFIGURATION 
            % -----------------------------------------

            if strcmp(app.UIAxesPanelConfigsProductionTotalBI.Visible, 'off')
                  app.UIAxesPanelConfigsProductionTotalBI.Visible = 'on';
            end
            
            configs = struct();

            % Extract panel specs
            configs.capacity = responseBI.solarPotential.panelCapacityWatts;
            configs.lifetime = responseBI.solarPotential.panelLifetimeYears;

            % Extract configuration data
            n_configs = length(responseBI.solarPotential.solarPanelConfigs);
            configs.panels = zeros(1, n_configs);
            configs.energy = zeros(1, n_configs);

            panelCapacityRatio = app.SelectedPanelCapacity/app.DefaultPanelCapacity;
            
            for i = 1:n_configs
                config = responseBI.solarPotential.solarPanelConfigs(i);
                configs.panels(i) = config.panelsCount;
                % Assume linear scaling by panel capacity
                configs.energy(i) = panelCapacityRatio*config.yearlyEnergyDcKwh;
            end

            % Plot
            plot(app.UIAxesPanelConfigsProductionTotalBI,configs.panels, configs.energy, '-', 'LineWidth', 2);
            hold(app.UIAxesPanelConfigsProductionTotalBI, 'on'); 
            % average_energy = mean(configs.energy); 
            % plot(app.UIAxesPanelConfigsProductionTotalBI, [min(configs.panels),...
            %     max(configs.panels)], [average_energy, average_energy], 'r--', 'LineWidth', 2); 
            plot(app.UIAxesPanelConfigsProductionTotalBI, [app.NumberOfPanels, app.NumberOfPanels],...
                 [0, max(configs.energy)], 'k--', 'LineWidth', 1);
            hold(app.UIAxesPanelConfigsProductionTotalBI, 'off');
            app.UIAxesPanelConfigsProductionTotalBI.Title.String = 'Yearly Energy Production by Configuration';
            app.UIAxesPanelConfigsProductionTotalBI.Title.FontSize = 10;
            app.UIAxesPanelConfigsProductionTotalBI.XLabel.String = 'Number of Panels';
            app.UIAxesPanelConfigsProductionTotalBI.YLabel.String = 'Yearly Energy (kWh/year DC)';
            % legend(app.UIAxesPanelConfigsProductionTotalBI, {'Energy Prod.','Mean Energy Prod. (Max Array)',...
            %     'Selected Panel Array'}, 'Location', 'northwest', 'FontSize', 8);
            legend(app.UIAxesPanelConfigsProductionTotalBI, {'Energy Prod.',...
                'Selected Panel Array'}, 'Location', 'northwest', 'FontSize', 8);
        end
        
        function addAPICoverageMap(app)
            % Read shapefiles
            high = readgeotable("./solar-api-coverage/SolarAPIHighArea.shp");
            medium = readgeotable("./solar-api-coverage/SolarAPIMediumArea.shp");
        
            % Convert geotable to table
            HIGH = geotable2table(high, ["Lat", "Lon"]);
            MEDIUM = geotable2table(medium, ["Lat", "Lon"]);
        
            % Convert cell arrays to numeric arrays
            HIGH.Lon = cell2mat(HIGH.Lon);
            HIGH.Lat = cell2mat(HIGH.Lat);
            MEDIUM.Lon = cell2mat(MEDIUM.Lon);
            MEDIUM.Lat = cell2mat(MEDIUM.Lat);
        
            % Clear the axes before plotting
            cla(app.GeoCoverageMap);
        
            % Create a geoaxes if not already present
            if isempty(app.APICoverageMap.Children)
                % Create geoaxes and associate it with app.APICoverageMap
                app.GeoCoverageMap = geoaxes('Parent', app.APICoverageMap, ...
                    'Position', [0.05, 0.05, 0.85, 0.85]); % Adjust position as needed
                geobasemap(app.GeoCoverageMap, 'grayland'); % Adjust basemap if necessary
            end
        
            % Plot coverage map
            hold(app.GeoCoverageMap, 'on');

            % Plot high and medium coverage areas
            geoscatter(app.GeoCoverageMap, HIGH.Lat, HIGH.Lon, 10, 'r', 'filled', 'DisplayName', 'High Area');
            geoscatter(app.GeoCoverageMap, MEDIUM.Lat, MEDIUM.Lon, 10, 'b', 'filled', 'DisplayName', 'Medium Area');
        
            % Customize plot
            title(app.GeoCoverageMap, 'Solar API Coverage');
            legend(app.GeoCoverageMap, 'High Area', 'Medium Area', 'Location', 'southoutside');
            hold(app.GeoCoverageMap, 'off');      
        end
        
        function masked_data = apply_roof_mask(~, response, api_key, data, data_type)
            % Apply roof mask to other data layers
            %
            % Parameters:
            %   response: API response containing URLs
            %   api_key: Google API key
            %   data: Original data to mask
            %   data_type: Type of data ('monthly_flux' or 'hourly_shade')
            
            % Download roof mask
            mask_filename = 'temp_mask.tif';
            mask_url = sprintf('%s&key=%s', response.maskUrl, api_key);
            websave(mask_filename, mask_url);
            [mask_data, ~] = readgeoraster(mask_filename);
            
            % Create binary mask (1 for roof, 0 for non-roof)
            binary_mask = mask_data > 0;
            
            % Get target dimensions from the first layer of input data
            target_size = size(data(:,:,1));
            
            % Resize mask to match data dimensions using imresize
            binary_mask = imresize(binary_mask, target_size, 'nearest');
            
            % Initialize masked data with same size as input
            masked_data = data;
            
            % Apply mask based on data type
            switch data_type
                case 'monthly_flux'
                    % For monthly flux (12 bands)
                    masked_data = data; % Initialize with original data
                    for i = 1:size(data, 3)
                        current_layer = data(:,:,i);
                        current_layer(~binary_mask) = NaN;  % or use zero
                        masked_data(:,:,i) = current_layer;
                    end
                    
                case 'hourly_shade'
                    % For hourly shade (24 bands)
                    for i = 1:size(data, 3)
                        current_layer = data(:,:,i);
                        % Only mask valid data points
                        mask_indices = ~binary_mask & current_layer ~= -9999;
                        current_layer(mask_indices) = -9999;  % Use invalid marker
                        masked_data(:,:,i) = current_layer;
                    end
            end
            
            % Clean up
            delete(mask_filename);
        end
        
        function animateHourlyShade(~, shade_data, day, apply_mask)
            % Create animation of hourly shade for a specific date
            %
            % Parameters:
            %   response: API response containing hourly shade URLs
            %   api_key: Google API key
            %   month: Month (1-12)
            %   day: Day (1-31, depending on month)
            %   apply_mask: (optional) boolean to apply roof mask (default: false)
            
            % Create figure
            fig = figure('Name', sprintf('Creating Hourly Shade Animation'));
            
            % Create animation
            for hour = 1:24
                % Get data for current hour
                hourly_data = shade_data(:,:,hour);
                day_mask = bitget(hourly_data, day);
                
                % Create visualization data
                if apply_mask
                    % Create visualization data
                    vis_data = zeros(size(day_mask));

                    % Check if it's nighttime (all valid pixels are shaded)
                    is_night = all(day_mask(hourly_data ~= -9999) == 0);
                    if is_night
                        vis_data(hourly_data == -9999) = 0.5;  % Non-roof areas
                        vis_data(hourly_data ~= -9999 & day_mask == 0) = 0;  % Shaded roof areas
                        vis_data(hourly_data ~= -9999 & day_mask == 1) = 1;  % Sunny roof areas
                        
                        imagesc(vis_data);
                        colormap([61/256 38/256 168/256; 0.7 0.7 0.7]);
                        cbar = colorbar;
                        cbar.Ticks = [0.1 0.35];
                        cbar.TickLabels = {'Shade', 'Non-roof'};
                    else
                        % Normal daytime visualization
                        vis_data(hourly_data == -9999) = 0.5;  % Non-roof areas
                        vis_data(hourly_data ~= -9999 & day_mask == 0) = 0;  % Shaded roof areas
                        vis_data(hourly_data ~= -9999 & day_mask == 1) = 1;  % Sunny roof areas
                        imagesc(vis_data);
                        colormap(gca, [61/256 38/256 168/256; 0.7 0.7 0.7; 249/256 251/256 20/256]);
                        cbar = colorbar;
                        cbar.Ticks = [0.17 0.5 0.83];
                        cbar.TickLabels = {'Shade', 'Non-roof', 'Sun'};
                    end
                else
                    % For non-masked data, handle nighttime similarly
                    vis_data = day_mask;
                    is_night = all(day_mask(hourly_data ~= -9999) == 0);
        
                    if is_night
                        vis_data(hourly_data ~= -9999) = 0;  % All
                        imagesc(vis_data);
                        colormap(gca, [61/256 38/256 168/256]);
                        colorbar(gca, 'Ticks', 0.0, 'TickLabels', {'Shade'});
                    else
                        vis_data(hourly_data == -9999) = 0.5;  % Invalid
                        imagesc(vis_data);
                        colormap(gca, [61/256 38/256 168/256; 249/256 251/256 20/256]);
                        cbar = colorbar(gca);
                        cbar.Ticks = [0.28 0.72];
                        cbar.TickLabels = {'Shade', 'Sun'};
                    end
                end
                
                % title(sprintf('Shade Data'));
                % title(sprintf(''));
                axis equal tight;
                
                % Add time stamp
                text(10, size(day_mask,1)-10, sprintf('%02d:00', hour-1), ...
                    'Color', 'red', 'FontSize', 14);
                
                % Capture frame for video if needed
                frames(hour) = getframe(fig);
            end

            close(fig)
            
            % Save as video with slower frame rate
            v = VideoWriter(sprintf('hourly_shade.mp4'),'MPEG-4');
            v.FrameRate = 6;  % 6 frames per second
            
            % You can also duplicate each frame to make it last longer
            
            frame_duration = 3; % Number of times each frame is repeated
            extended_frames = [];
            
            for i = 1:length(frames)
                for j = 1:frame_duration
                    extended_frames = [extended_frames, frames(i)];
                end
            end
            
            open(v);
            writeVideo(v, extended_frames);
            close(v);
        end
   
        function foundData = fetchDL(app)
            % Data Layers API Call
            successDL = false; % Flag to track if a successful response was obtained
            try
                for quality = app.Quality
                    % Create the URL for the current quality level
                    geoTiffURL = app.create_data_layers_url(app.GM_API_KEY, app.SelectedLatitude, ...
                                                        app.SelectedLongitude, app.geoTiffZoomLevel, ...
                                                        quality);
                    try
                        % Attempt to get the response
                        app.responseDL = webread(geoTiffURL);
                        if ischar(app.responseDL)
                            app.responseDL = jsondecode(app.responseDL);
                        end
                        % If successful, set flag and exit loop
                        % disp("Data Layers success");
                        successDL = true;
                        foundData = true;
                        break; % Exit the loop
                    catch
                        % If an error occurs, continue to the next quality level
                        continue;
                    end
                end
                if ~successDL
                    % If no quality level succeeded, show an alert
                    uialert(app.UIFigure, 'Unable to find Data Layers data', 'Error');
                    foundData = false;
                end
            catch
                uialert(app.UIFigure, 'Unable to find Data Layers data', 'Error');
                foundData = false;
            end
        end
        
        function foundData = fetchBI(app)
            % Building Insights API Call
            successBI = false; % Flag to track if a successful response was obtained
            try
                for quality = app.Quality
                    % Create the URL for the current quality level
                    buildingInsightsURL = app.create_building_insights_url(app.GM_API_KEY, ...
                                                                       app.SelectedLatitude, ...
                                                                       app.SelectedLongitude, ...
                                                                       quality);
                    try
                        % Attempt to get the response
                        app.responseBI = webread(buildingInsightsURL);
                        if ischar(app.responseBI)
                            app.responseBI = jsondecode(app.responseBI);
                        end
                        % If successful, set flag and exit loop
                        % disp("Building Insights success");
                        successBI = true;
                        foundData = true;
                        break; % Exit the loop
                    catch
                        % If an error occurs, continue to the next quality level
                        continue;
                    end
                end
                if ~successBI
                    % If no quality level succeeded, show an alert
                    uialert(app.UIFigure, 'Unable to find Building Insights data', 'Error');
                    foundData = false;
                end
            catch
                uialert(app.UIFigure, 'Unable to find Building Insights data', 'Error');
                foundData = false;
            end
        end
        
        function url = create_data_layers_url(~, api_key, latitude, longitude, radius_meters, required_quality)
            % Generate URL for Google Solar API Data Layers endpoint
            %
            % Parameters:
            %   api_key (string): Google Maps API key
            %   latitude (double): Latitude of the location
            %   longitude (double): Longitude of the location
            %   radius_meters (integer): Radius in meters (zoom level)
            %
            % Returns:
            %   url (string): Complete URL for the API request
            
            url = sprintf(['https://solar.googleapis.com/v1/dataLayers:get?', ...
                'location.latitude=%.4f&location.longitude=%.4f&', ...
                'radiusMeters=%d&view=FULL_LAYERS&requiredQuality=%s&', ...
                'exactQualityRequired=true&pixelSizeMeters=0.5&key=%s'], ...
                latitude, longitude, radius_meters, required_quality, api_key);
        end
        
        function url = create_building_insights_url(~, api_key, latitude, longitude, required_quality)
            % Generate URL for Google Solar API Building Insights endpoint
            %
            % Parameters:
            %   api_key (string): Google Maps API key
            %   latitude (double): Latitude of the location
            %   longitude (double): Longitude of the location
            %
            % Returns:
            %   url (string): Complete URL for the API request
            url = sprintf(['https://solar.googleapis.com/v1/buildingInsights:findClosest?', ...
                'location.latitude=%.4f&location.longitude=%.4f&', ...
                'requiredQuality=%s&key=%s'], latitude, longitude, required_quality, api_key);
        end
        
        function addGoogleMap(app, zoom)
            % Google Maps: Zoom 15
           [XX, YY, M, Mcolor] = app.get_google_map(app.SelectedLatitude, app.SelectedLongitude, 'APIKey', ...
               app.GM_API_KEY, 'Zoom', zoom, 'Marker', sprintf('%.7f,%.7f', app.SelectedLatitude, app.SelectedLongitude));
           % Display the map in the UIAxes
           imagesc(app.UIAxesGoogleMap, XX, YY, M);
           colormap(app.UIAxesGoogleMap,Mcolor);
           % Adjust axis scaling and appearance
           axis(app.UIAxesGoogleMap, 'image');  % Correct aspect ratio
           app.UIAxesGoogleMap.XLim = [min(XX), max(XX)];
           app.UIAxesGoogleMap.YLim = [min(YY), max(YY)];
        end
        
        function disableUIElements(app)
            % app.UIAxesSunshineQuantilesBI

            % MAKE EXPORT DATA BTN INVISIBLE
            % if strcmp(app.ExportDataButton.Visible, 'on')
            %     app.ExportDataButton.Visible = 'off';
            % end
            % MAKE ZOOM DROPDOWN INVISIBLE
            if strcmp(app.SelectZoomLevelDropDown.Visible, 'on')
                app.SelectZoomLevelDropDown.Visible = 'off';
            end
            % MAKE PLAY BTN INVISIBLE
            if strcmp(app.PlayButton.Visible,'on')
                app.PlayButton.Visible = 'off';
            end
            
            % MAKE PANEL CAPACITY DROPDOWN INVISIBLE
            if strcmp(app.PanelCapacityDropDown.Visible, 'on')
                app.PanelCapacityDropDown.Visible = 'off';
            end
            % app.NumberofPanelsSlider app.EnergyProductionkWhyearEditField
            % app.PercentofMaxArrayEditField app.NumberofPanelsEditField app.RefreshButton
            % MAKE NUMBER OF PANELS SLIDER INVISIBLE
            if strcmp(app.NumberofPanelsSlider.Visible,'on')
                app.NumberofPanelsSlider.Visible = 'off';
            end
            if strcmp(app.NumberofPanelsEditField.Visible,'on')
                app.NumberofPanelsEditField.Visible = 'off';
            end
            if strcmp(app.EnergyProductionkWhyearEditField.Visible,'on')
                app.EnergyProductionkWhyearEditField.Visible = 'off';
            end
            if strcmp(app.PercentofMaxArrayEditField.Visible,'on')
                app.PercentofMaxArrayEditField.Visible = 'off';
            end
            % MAKE REFRESH BUTTON INVISIBLE
            if strcmp(app.RefreshButton.Visible,'on')
                app.RefreshButton.Visible = 'off';
            end
            
            if strcmp(app.UIAxesRGB.Visible, 'on')
                app.UIAxesRGB.Visible = 'off';
            end
            if strcmp(app.UIAxesDSM.Visible, 'on')
                app.UIAxesDSM.Visible = 'off';
            end
            if strcmp(app.UIAxesMask.Visible, 'on')
                app.UIAxesMask.Visible = 'off';
            end
            if strcmp(app.UIAxesAnnualFlux.Visible, 'on')
                app.UIAxesAnnualFlux.Visible = 'off';
            end
            
            % HIDE SELECT DL
            cla(app.UIAxesDLRaw, 'reset')
            if strcmp(app.UIAxesDLRaw.Visible, 'on')
                app.UIAxesDLRaw.Visible = 'off';
            end
            cla(app.UIAxesDL3D, 'reset')
            if strcmp(app.UIAxesDL3D.Visible, 'on')
                app.UIAxesDL3D.Visible = 'off';
            end
            cla(app.UIAxesDLContour, 'reset')
            if strcmp(app.UIAxesDLContour.Visible, 'on')
                app.UIAxesDLContour.Visible = 'off';
            end
            cla(app.UIAxesDLDistribution, 'reset')
            if strcmp(app.UIAxesDLDistribution.Visible, 'on')
                app.UIAxesDLDistribution.Visible = 'off';
            end
            % app.SelectDataLayerDropDown
            if strcmp(app.SelectDataLayerDropDown.Visible, 'on')
                app.SelectDataLayerDropDown.Visible = 'off';
            end
            
            % MONTHLY FLUX
            % app.UIAxesMonthlyFlux app.UIAxesMonthly3D
            % app.UIAxesMonthlyContour app.UIAxesMonthlyDistribution
            cla(app.UIAxesMonthlyFlux, 'reset')
            if strcmp(app.UIAxesMonthlyFlux.Visible, 'on')
                app.UIAxesMonthlyFlux.Visible = 'off';
            end
            cla(app.UIAxesMonthly3D, 'reset')
            if strcmp(app.UIAxesMonthly3D.Visible, 'on')
                app.UIAxesMonthly3D.Visible = 'off';
            end
            cla(app.UIAxesMonthlyContour,'reset')
            if strcmp(app.UIAxesMonthlyContour.Visible, 'on')
                app.UIAxesMonthlyContour.Visible = 'off';
            end
            cla(app.UIAxesMonthlyDistribution, 'reset')
            if strcmp(app.UIAxesMonthlyDistribution.Visible, 'on')
                app.UIAxesMonthlyDistribution.Visible = 'off';
            end
            % app.SelectMonthDropDown app.SwitchRoofMaskMonthly
            if strcmp(app.SelectMonthDropDown.Visible, 'on')
                app.SelectMonthDropDown.Visible = 'off';
            end
            if strcmp(app.SwitchRoofMaskMonthly.Visible, 'on')
                app.SwitchRoofMaskMonthly.Visible = 'off';
            end
            
            % HOURLY SHADE
            % Disable axes
            % app.UIAxesHourlyShadeData app.UIAxesHourlyShadeVisibility
            % app.UIAxesHourlyShadeValidity app.UIAxesHourlyShadeDistribution
            cla(app.UIAxesHourlyShadeData, 'reset')
            if strcmp(app.UIAxesHourlyShadeData.Visible, 'on')
                app.UIAxesHourlyShadeData.Visible = 'off';
            end
            cla(app.UIAxesHourlyShadeVisibility, 'reset')
            if strcmp(app.UIAxesHourlyShadeVisibility.Visible, 'on')
                app.UIAxesHourlyShadeVisibility.Visible = 'off';
            end
            % if strcmp(app.UIAxesHourlyShadeValidity.Visible, 'on')
            %     app.UIAxesHourlyShadeValidity.Visible = 'off';
            % end
            cla(app.UIAxesHourlyShadeDistribution, 'reset')
            if strcmp(app.UIAxesHourlyShadeDistribution.Visible, 'on')
                app.UIAxesHourlyShadeDistribution.Visible = 'off';
            end
            % Disable controls
            % app.SelectMonthDropDownHourly app.SelectDayDropDownHourly
            % app.SelectHourDropDownHourly app.GetHourlyShadeButton
            % app.SwitchRoofMaskHourly
            if strcmp(app.SelectMonthDropDownHourly.Visible, 'on')
                app.SelectMonthDropDownHourly.Visible = 'off';
            end
            if strcmp(app.SelectDayDropDownHourly.Visible, 'on')
                app.SelectDayDropDownHourly.Visible = 'off';
            end
            if strcmp(app.SelectHourDropDownHourly.Visible, 'on')
                app.SelectHourDropDownHourly.Visible = 'off';
            end
            if strcmp(app.GetHourlyShadeButton.Visible, 'on')
                app.GetHourlyShadeButton.Visible = 'off';
            end
            if strcmp(app.SwitchRoofMaskHourly.Visible, 'on')
                app.SwitchRoofMaskHourly.Visible = 'off';
            end
            % app.UIAxesHourlyShadeAnimation
            if strcmp(app.UIAxesHourlyShadeAnimation.Visible, 'on')
                app.UIAxesHourlyShadeAnimation.Visible = 'off';
            end
            
            % MAKE BI TABLES INVISIBLE
            if strcmp(app.UITableBuildingInformation.Visible, 'on')
                app.UITableBuildingInformation.Visible = 'off';
            end
            if strcmp(app.UITableSolarPotential.Visible, 'on')
                app.UITableSolarPotential.Visible = 'off';
            end
            if strcmp(app.UITablePanelStats.Visible,'on')
                app.UITablePanelStats.Visible = 'off';
            end
            if strcmp(app.UITableRoofStats.Visible,'on')
                app.UITableRoofStats.Visible = 'off';
            end
            if strcmp(app.UITableImageryInfo.Visible, 'on')
                app.UITableImageryInfo.Visible = 'off';
            end
            
            % SOLAR PANELS
            cla(app.UIAxesPanelConfigsProductionPerPanelBI, 'reset')
            if strcmp(app.UIAxesPanelConfigsProductionPerPanelBI.Visible, 'on')
                app.UIAxesPanelConfigsProductionPerPanelBI.Visible = 'off';
            end
            cla(app.UIAxesPanelConfigsPanelsPerSegmentBI, 'reset')
            if strcmp(app.UIAxesPanelConfigsPanelsPerSegmentBI.Visible, 'on')
                app.UIAxesPanelConfigsPanelsPerSegmentBI.Visible = 'off';
            end
            cla(app.UIAxesPanelConfigsEnergyPerPanelHistBI, 'reset')
            if strcmp(app.UIAxesPanelConfigsEnergyPerPanelHistBI.Visible, 'on')
                app.UIAxesPanelConfigsEnergyPerPanelHistBI.Visible = 'off';
            end
            if strcmp(app.UITableIndividualPanelsStatsBI.Visible, 'on')
                app.UITableIndividualPanelsStatsBI.Visible = 'off';
            end
            if strcmp(app.OptimiseButtonGroup.Visible, 'on')
                app.OptimiseButtonGroup.Visible = 'off';
            end
            if strcmp(app.PanelLocationsandEnergyProductionkWhYear.Visible,'on')
                app.PanelLocationsandEnergyProductionkWhYear.Visible = 'off';
            end
            if strcmp(app.RoofSegmentLocationsPanel.Visible, 'on')
                app.RoofSegmentLocationsPanel.Visible = 'off';
            end
            if strcmp(app.UITableIndividualPanelsBI.Visible, 'on')
                app.UITableIndividualPanelsBI.Visible = 'off';
            end
            if strcmp(app.UITableSelectedArray.Visible, 'on')
                app.UITableSelectedArray.Visible = 'off';
            end
            if strcmp(app.MaxArrayIndividualPanelStatistics.Visible, 'on')
                app.MaxArrayIndividualPanelStatistics.Visible = 'off';
            end
            if strcmp(app.SelectedArrayIndividualPanelsStatistics.Visible, 'on')
                app.SelectedArrayIndividualPanelsStatistics.Visible = 'off';
            end
            if strcmp(app.IndividualPanelsMaxArray.Visible, 'on')
                app.IndividualPanelsMaxArray.Visible = 'off';
            end
            if strcmp(app.IndividualPanelsSelectedArray.Visible, 'on')
                app.IndividualPanelsSelectedArray.Visible = 'off';
            end
            cla(app.UIAxesSelectedPanelsHist, 'reset')
            if strcmp(app.UIAxesSelectedPanelsHist.Visible, 'on')
                app.UIAxesSelectedPanelsHist.Visible = 'off';
            end
            
            % ROOF SEGMENTS
            if strcmp(app.UITableRoofSegmentInfo.Visible, 'on')
                app.UITableRoofSegmentInfo.Visible = 'off';
            end
            if strcmp(app.NumberofSegmentsbyIndexSlider.Visible, 'on')
                app.NumberofSegmentsbyIndexSlider.Visible = 'off';
            end
            if strcmp(app.SegmentIndexEditField.Visible, 'on')
                app.SegmentIndexEditField.Visible = 'off';
            end
            if strcmp(app.SegmentIndexEnergyProductionkWhyearEditField.Visible, 'on')
                app.SegmentIndexEnergyProductionkWhyearEditField.Visible = 'off';
            end
            if strcmp(app.SegmentIndexPercentofMaxArrayEditField.Visible, 'on')
                app.SegmentIndexPercentofMaxArrayEditField.Visible = 'off';
            end
            if strcmp(app.SegmentIndexRefreshButton.Visible, 'on')
                app.SegmentIndexRefreshButton.Visible = 'off';
            end
            
            % SUNSHINE QUANTILES
            cla(app.UIAxesSunshineQuantilesBI, 'reset')
            if strcmp(app.UIAxesSunshineQuantilesBI.Visible, 'on')
                app.UIAxesSunshineQuantilesBI.Visible = 'off';
            end
            if strcmp(app.UITableSunshineQuantilePctBI.Visible, 'on')
                app.UITableSunshineQuantilePctBI.Visible = 'off';
            end
            
            % ROOF SEGMENT PLOTS
            cla(app.UIAxesRoofSegmentAreaHeightBI, 'reset')
            if strcmp(app.UIAxesRoofSegmentAreaHeightBI.Visible, 'on')
                app.UIAxesRoofSegmentAreaHeightBI.Visible = 'off';
            end
            cla(app.UIAxesRoofSegmentOrientationsBI, 'reset')
            if strcmp(app.UIAxesRoofSegmentOrientationsBI.Visible, 'on')
                app.UIAxesRoofSegmentOrientationsBI.Visible = 'off';
            end
            
            % PANEL CONFIGURATION
            cla(app.UIAxesPanelConfigsProductionTotalBI, 'reset')
            if strcmp(app.UIAxesPanelConfigsProductionTotalBI.Visible, 'on')
                app.UIAxesPanelConfigsProductionTotalBI.Visible = 'off';
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Prompt the user for the Google Maps API key
           apiKey = inputdlg({'  Enter your Google Maps API Key:  '}, ...
                              'Google Maps Authentication', [1 50]);
            
           % Validate the input
           if isempty(apiKey)
               % User pressed cancel or did not provide an input
               uialert(app.UIFigure, 'API Key is required to use the app.', ...
                        'Authentication Error', 'Icon', 'error');
               delete(app); % Close the app
               return;
           end
            
           % Store the key in the app property
           app.GM_API_KEY = string(apiKey{1});
           % Set default location in London
           app.SelectedLatitude = 51.512703;
           app.SelectedLongitude = -0.090948;
           % Set zoom latlng label
           app.LatLngLabel.Text = sprintf("(LAT:%.6f, LNG:%.6f)", app.SelectedLatitude, app.SelectedLongitude);
           app.ZoomLevelSlider.Value = app.ZoomLevel;
           app.LatitudeSlider.Value = app.SelectedLatitude;
           app.LongitudeSlider.Value = app.SelectedLongitude;
           app.LatitudeEditField.Value = app.SelectedLatitude;
           app.LongitudeEditField.Value = app.SelectedLongitude;
           % Add Google Maps
           % Get Address
           try
                app.BuildingAddress = app.getAddress();
           catch
                app.BuildingAddress = "";
           end
           app.FormattedAddressLabel.Text = app.BuildingAddress;
           app.DLLocationLabel.Text = app.BuildingAddress;
           % Google Maps: Zoom 15
           app.addGoogleMap(15)
           % DATA LAYERS
           % Zoom Value Items
           app.SelectZoomLevelDropDown.Items = ["25 m", "50 m", "75 m", "100 m", "125 m", "150 m", "175 m"]; 
           app.SelectZoomLevelDropDown.Value = "100 m";
           % Monthly Shade
           app.SelectMonthDropDown.Items = ["January","February","March","April","June","July","August","September",...
               "October","November","December"];
           app.SelectMonthDropDown.Value = "July";
           % Hourly Shade
           app.SelectMonthDropDownHourly.Items = ["January","February","March","April","June","July","August","September",...
               "October","November","December"];
           app.SelectDayDropDownHourly.Items = string(1:31);
           app.SelectHourDropDownHourly.Items = string(1:24);
           app.SelectMonthDropDownHourly.Value = "July";
           app.SelectDayDropDownHourly.Value = string(15);
           app.SelectHourDropDownHourly.Value = string(12);
           % ADD GEOAXES
           % Single panel locations
           app.PanelLocationsandEnergyProductionkWhYear.Visible = 'off';
           app.SinglePanelsLocations = geoaxes('Parent',app.PanelLocationsandEnergyProductionkWhYear, ...
                'Position',[0.05 0.05 0.85 0.85]);
           geobasemap(app.SinglePanelsLocations,'satellite')
           % Roof segment locations
           app.RoofSegmentLocationsPanel.Visible = 'off';
           app.RoofSegmentLocations = geoaxes('Parent',app.RoofSegmentLocationsPanel, 'Position',[0.05 0.05 0.85 0.85]);
           geobasemap(app.RoofSegmentLocations,'satellite');
           % API Coverage
           app.GeoCoverageMap = geoaxes('Parent', app.APICoverageMap, ...
            'Position', [0.05, 0.05, 0.85, 0.85]); % Adjust position as needed
           geobasemap(app.GeoCoverageMap, 'grayland');
           % BUILDING INSIGHTS
           % Panel capacity items
           app.PanelCapacityDropDown.Items = ["200 W", "300 W", "400 W", "500 W", "600 W", "700 W", "800 W"];
           app.PanelCapacityDropDown.Value = "400 W";
           % Add API coverage map
           app.addAPICoverageMap();
           % Set panel border colors, if they are not correctly assigned
           app.PanelLocationsandEnergyProductionkWhYear.BorderColor = [1 1 1];
           app.RoofSegmentLocationsPanel.BorderColor = [1 1 1];
           app.SolarPotentialPanel.BorderColor = [1 1 1];
           app.PanelInfo.BorderColor = [1 1 1];
           app.RoofSegmentInfoPanel.BorderColor = [1 1 1];
           app.WholeRoofInfoPanel.BorderColor = [1 1 1];
           app.BuildingInfoPanel.BorderColor = [1 1 1];
           app.ImageryInfoPanel.BorderColor = [1 1 1];
           app.MaxArrayIndividualPanelStatistics.BorderColor = [1 1 1];
           app.QuantilePercentageContributionPanel.BorderColor = [1 1 1];
           app.APICoverageMap.BorderColor = [1 1 1];
           app.SelectedArrayIndividualPanelsStatistics.BorderColor = [1 1 1];
           app.IndividualPanelsSelectedArray.BorderColor = [1 1 1];
           app.IndividualPanelsMaxArray.BorderColor = [1 1 1];
           app.OptimiseButtonGroup.BorderColor = [1 1 1];
           %
           app.PlayButton.Icon = "play.png";
           %
           app.OptimiseSelection = "No";
        end

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
            address = app.EnterstreetaddressEditField.Value; % Get the entered address
            geocodeUrl = sprintf(app.GoogleMapsBaseURL + app.GM_API_KEY, ...
                urlencode(address));
        
            % Fetch the geocode response
            try
                response = webread(geocodeUrl);
            catch
                uialert(app.UIFigure, 'No address data about the location.', 'Error');
                return
            end

            if strcmp(response.status, 'OK')
               try
                    app.SelectedLatitude = response.results(1).geometry.location.lat;
                    app.SelectedLongitude = response.results(1).geometry.location.lng;
               catch
                    % disp("Dot indexing is not supported for variables of this type.");
                    uialert(app.UIFigure, 'Unable to find location data.', 'Error');
                    return;
               end
               % Update sliders
               % app.ZoomLevelSlider.Value = app.ZoomLevel;
               app.LatitudeSlider.Value = app.SelectedLatitude;
               app.LongitudeSlider.Value = app.SelectedLongitude;
               app.LatitudeEditField.Value = app.SelectedLatitude;
               app.LongitudeEditField.Value = app.SelectedLongitude;

               % Update the map
               app.addGoogleMap(app.ZoomLevelSlider.Value)

               % Update map label
               formatted_address = response.results(1).formatted_address;
               app.FormattedAddressLabel.Text = formatted_address;
               app.DLLocationLabel.Text = formatted_address;
            else
                uialert(app.UIFigure, 'Unable to find location', 'Error');
                return
            end                                         
        end

        % Value changed function: ZoomLevelSlider
        function ZoomLevelSliderValueChanged(app, event)
            % zoomValue = int16(app.ZoomLevelSlider.Value);
            zoomValue = app.ZoomLevelSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(zoomValue - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % override the selected value
            zoomValue = event.Source.MajorTicks(minIdx);
           
            % Update the map
            app.addGoogleMap(zoomValue)
        end

        % Value changed function: LatitudeSlider
        function LatitudeSliderValueChanged(app, event)
            % Update the selected latitude
            app.SelectedLatitude = app.LatitudeSlider.Value;

            % Update the latitude edit field
            app.LatitudeEditField.Value = app.SelectedLatitude;

            % Set zoom latlng label
            app.LatLngLabel.Text = sprintf("(LAT:%.6f, LNG:%.6f)", app.SelectedLatitude, app.SelectedLongitude);

            % Update the map
            app.addGoogleMap(int16(app.ZoomLevelSlider.Value))

            % Update the map label
            latlong = [num2str(app.SelectedLatitude), ',', num2str(app.SelectedLongitude)];
            reverseGeocodeURL = sprintf(app.GoogleMapsReverseGeocodeURL + app.GM_API_KEY, urlencode(latlong));
            response = webread(reverseGeocodeURL);
            if isfield(response.results, 'formatted_address')
                app.FormattedAddressLabel.Text = response.results(1).formatted_address;
            else
                app.FormattedAddressLabel.Text = 'No address found';
            end         
        end

        % Value changed function: LongitudeSlider
        function LongitudeSliderValueChanged(app, event)
            % Update the selected longitude
            app.SelectedLongitude = app.LongitudeSlider.Value;

            % Update the longitude edit field
            app.LongitudeEditField.Value = app.SelectedLongitude;

            % Set zoom latlng label
            app.LatLngLabel.Text = sprintf("(LAT:%.6f, LNG:%.6f)", app.SelectedLatitude, app.SelectedLongitude);

            % Update the map
            app.addGoogleMap(int16(app.ZoomLevelSlider.Value))

            % Update the map label
            latlong = [num2str(app.SelectedLatitude), ',', num2str(app.SelectedLongitude)];
            reverseGeocodeURL = sprintf(app.GoogleMapsReverseGeocodeURL + app.GM_API_KEY, urlencode(latlong));
            response = webread(reverseGeocodeURL);
            if isfield(response.results, 'formatted_address')
                app.FormattedAddressLabel.Text = response.results(1).formatted_address;
            else
                app.FormattedAddressLabel.Text = 'No address found';
            end    
        end

        % Value changed function: LatitudeEditField
        function LatitudeEditFieldValueChanged(app, event)
            app.SelectedLatitude = app.LatitudeEditField.Value;

            % Update latitude slider value
            app.LatitudeSlider.Value = app.SelectedLatitude;

            % Set zoom latlng label
            app.LatLngLabel.Text = sprintf("(LAT:%.6f, LNG:%.6f)", app.SelectedLatitude, app.SelectedLongitude);

            % Update the map    
            app.addGoogleMap(int16(app.ZoomLevelSlider.Value))

            % Update the map label
            latlong = [num2str(app.SelectedLatitude), ',', num2str(app.SelectedLongitude)];
            reverseGeocodeURL = sprintf(app.GoogleMapsReverseGeocodeURL + app.GM_API_KEY, urlencode(latlong));
            response = webread(reverseGeocodeURL);
            if isfield(response.results, 'formatted_address')
                app.FormattedAddressLabel.Text = response.results(1).formatted_address;
            else
                app.FormattedAddressLabel.Text = 'No address found';
            end    
        end

        % Value changed function: LongitudeEditField
        function LongitudeEditFieldValueChanged(app, event)
            app.SelectedLongitude = app.LongitudeEditField.Value;

            % Update longitude slider value
            app.LongitudeSlider.Value = app.SelectedLongitude;

            % Set zoom latlng label
            app.LatLngLabel.Text = sprintf("(LAT:%.6f, LNG:%.6f)", app.SelectedLatitude, app.SelectedLongitude);

            % Update the map 
            app.addGoogleMap(int16(app.ZoomLevelSlider.Value))

            % Update the map label
            latlong = [num2str(app.SelectedLatitude), ',', num2str(app.SelectedLongitude)];
            reverseGeocodeURL = sprintf(app.GoogleMapsReverseGeocodeURL + app.GM_API_KEY, urlencode(latlong));
            response = webread(reverseGeocodeURL);
            if isfield(response.results, 'formatted_address')
                app.FormattedAddressLabel.Text = response.results(1).formatted_address;
            else
                app.FormattedAddressLabel.Text = 'No address found';
            end    
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
            % progress bar
            fig = app.UIFigure;
            progressBar = uiprogressdlg(fig,'Title','Analysing data ...',...
                'Indeterminate','on');
            drawnow

            app.responseDL = {};
            app.responseBI = {};

            app.disableUIElements()
            
            % DATA LAYERS (DL)
            % ----------------
            % % Data Layers API Call
            % successDL = false; % Flag to track if a successful response was obtained
            % try
            %     for quality = app.Quality
            %         % Create the URL for the current quality level
            %         geoTiffURL = create_data_layers_url(app.GM_API_KEY, app.SelectedLatitude, ...
            %                                             app.SelectedLongitude, app.geoTiffZoomLevel, ...
            %                                             quality);
            %         try
            %             % Attempt to get the response
            %             app.responseDL = webread(geoTiffURL);
            %             if ischar(app.responseDL)
            %                 app.responseDL = jsondecode(app.responseDL);
            %             end
            %             % If successful, set flag and exit loop
            %             % disp("Data Layers success");
            %             successDL = true;
            %             break; % Exit the loop
            %         catch
            %             % If an error occurs, continue to the next quality level
            %             continue;
            %         end
            %     end
            %     if ~successDL
            %         % If no quality level succeeded, show an alert
            %         uialert(app.UIFigure, 'Unable to find Data Layers data', 'Error');
            %     end
            % catch
            %     uialert(app.UIFigure, 'Unable to find Data Layers data', 'Error');
            % end

            % FETCH DL DATA
            foundDL = app.fetchDL();

            if foundDL
                % MAKE EXPORT DATA BTN VISIBLE
                if strcmp(app.ExportDataButton.Visible, 'off')
                    app.ExportDataButton.Visible = 'on';
                end
                % MAKE ZOOM DROPDOWN VISIBLE
                if strcmp(app.SelectZoomLevelDropDown.Visible, 'off')
                    app.SelectZoomLevelDropDown.Visible = 'on';
                end
                % MAKE PLAY BTN VISIBLE
                if strcmp(app.PlayButton.Visible,'off')
                    app.PlayButton.Visible = 'on';
                end

                % DATA LAYERS: GENERAL
                app.showDataLayers(app.responseDL)
    
                % DATA LAYERS: SELECT DATA LAYER
                app.showSelectDataLayer(app.responseDL)
    
                % DATA LAYERS: MONTHLY FLUX
                app.showDataLayersMonthlyFlux(app.responseDL)
    
                % DATA LAYERS: HOURLY SHADE
                app.showDataLayersHourlyShade(app.responseDL)
            end

            % BUILDING INSIGHTS (BI)
            % ----------------------
            % % Building Insights API Call
            % successBI = false; % Flag to track if a successful response was obtained
            % try
            %     for quality = app.Quality
            %         % Create the URL for the current quality level
            %         buildingInsightsURL = create_building_insights_url(app.GM_API_KEY, ...
            %                                                            app.SelectedLatitude, ...
            %                                                            app.SelectedLongitude, ...
            %                                                            quality);
            %         try
            %             % Attempt to get the response
            %             app.responseBI = webread(buildingInsightsURL);
            %             if ischar(app.responseBI)
            %                 app.responseBI = jsondecode(app.responseBI);
            %             end
            %             % If successful, set flag and exit loop
            %             % disp("Building Insights success");
            %             successBI = true;
            %             break; % Exit the loop
            %         catch
            %             % If an error occurs, continue to the next quality level
            %             continue;
            %         end
            %     end
            %     if ~successBI
            %         % If no quality level succeeded, show an alert
            %         uialert(app.UIFigure, 'Unable to find Building Insights data', 'Error');
            %     end
            % catch
            %     uialert(app.UIFigure, 'Unable to find Building Insights data', 'Error');
            % end
            
            % FETCH BI DATA
            foundBI = app.fetchBI();

            if foundBI
                % MAKE PANEL CAPACITY DROPDOWN VISIBLE
                if strcmp(app.PanelCapacityDropDown.Visible, 'off')
                    app.PanelCapacityDropDown.Visible = 'on';
                end
                % app.NumberofPanelsSlider app.EnergyProductionkWhyearEditField
                % app.PercentofMaxArrayEditField app.NumberofPanelsEditField app.RefreshButton
                % MAKE NUMBER OF PANELS SLIDER VISIBLE
                if strcmp(app.NumberofPanelsSlider.Visible,'off')
                    app.NumberofPanelsSlider.Visible = 'on';
                end
                if strcmp(app.NumberofPanelsEditField.Visible,'off')
                    app.NumberofPanelsEditField.Visible = 'on';
                end
                if strcmp(app.EnergyProductionkWhyearEditField.Visible,'off')
                    app.EnergyProductionkWhyearEditField.Visible = 'on';
                end
                if strcmp(app.PercentofMaxArrayEditField.Visible,'off')
                    app.PercentofMaxArrayEditField.Visible = 'on';
                end
                % MAKE REFRESH BUTTON VISIIBLE
                if strcmp(app.RefreshButton.Visible,'off')
                    app.RefreshButton.Visible = 'on';
                end

                % Init number of panels
                app.MaxNumberOfPanels = length(app.responseBI.solarPotential.solarPanels);
                app.NumberOfPanels = round(app.MaxNumberOfPanels,0);
                % Set the number of panels slider limits; min 4
                app.NumberofPanelsSlider.Limits = [4,app.NumberOfPanels];
                app.NumberofPanelsSlider.MajorTicks = [4,app.NumberOfPanels];
                app.NumberofPanelsSlider.MinorTicks = [];
                app.NumberofPanelsSlider.Value = app.NumberOfPanels;
                app.NumberofPanelsEditField.Value = app.NumberOfPanels;
    
                % Init roof segment number
                app.MaxSegmentNumber = length(app.responseBI.solarPotential.roofSegmentStats) - 1;
                app.SegmentNumber = app.MaxSegmentNumber;
                if app.MaxSegmentNumber > 1
                    % Set slider limits and ticks
                    app.NumberofSegmentsbyIndexSlider.Limits = [0, app.MaxSegmentNumber];
                    app.NumberofSegmentsbyIndexSlider.MajorTicks = [0,app.MaxSegmentNumber];
                else
                    % Handle unexpected cases (e.g., no segments)
                    app.NumberofSegmentsbyIndexSlider.Limits = [0, 1];
                    app.NumberofSegmentsbyIndexSlider.MajorTicks = [0, 1];
                    uialert(app.UIFigure, 'One valid roof segment or no valid roof segments found.', 'Warning');
                end
                if app.MaxSegmentNumber <= 1
                    app.NumberofSegmentsbyIndexSlider.Enable = 'off';
                    app.SegmentIndexEditField.Editable = 'off';
                else
                    app.NumberofSegmentsbyIndexSlider.Enable = 'on';
                    app.SegmentIndexEditField.Editable = 'on';
                end
                app.NumberofSegmentsbyIndexSlider.MinorTicks = [];
                app.NumberofSegmentsbyIndexSlider.Value = app.SegmentNumber;
                app.SegmentIndexEditField.Value = app.SegmentNumber;
    
                % ADD MAIN TABLE DATA
                app.addTableDataBI(app.responseBI)
    
                % ADD SUNSHINE QUANTILES PLOT
                app.addQuantilesPlot(app.responseBI)
    
                % ADD ROOF SEGMENT PANEL ORIENTATION TABLE & PLOT
                app.addRoofSegmentsPlotAndTable(app.responseBI)
    
                % response:solarPanelConfigs - YEARLY ENERGY PRODUCTION BY CONFIGURATION
                app.addSolarPanelConfigsPlot(app.responseBI)
    
                % response:solarPanels - SINGLE PANEL DATA PLOTS AND TABLES 
                % SINGLE PANEL LOCATIONS, ROOF SEGMENT LOCATIONS ,ENERGY
                % PRODUCTION PER PANEL, SINGLE SOLAR PANELS TABLE, PANELS PER
                % SEGMENT
                app.addSolarPanelsMapsAndTables(app.responseBI)   
            end

            % close progress bar
            close(progressBar)
        end

        % Value changed function: SelectZoomLevelDropDown
        function SelectZoomLevelDropDownValueChanged(app, event)
            value = app.SelectZoomLevelDropDown.Value;
            
            switch value
                case "25 m"
                    app.geoTiffZoomLevel = 25;
                case "50 m"
                    app.geoTiffZoomLevel = 50;
                case "75 m"
                    app.geoTiffZoomLevel = 75;
                case "100 m"
                    app.geoTiffZoomLevel = 100;
                case "125 m"
                    app.geoTiffZoomLevel = 125;
                case "150 m"
                    app.geoTiffZoomLevel = 150;
                case "175 m"
                    app.geoTiffZoomLevel = 175;
                otherwise
                    app.geoTiffZoomLevel = 50;
            end
            
            % progress bar
            fig = app.UIFigure;
            progressBar = uiprogressdlg(fig,'Title','Analysing data ...',...
                'Indeterminate','on');
            drawnow
            
            % FETCH DL DATA
            foundDL = app.fetchDL();

            if foundDL
                % Data Layers: General
                app.showDataLayers(app.responseDL)
                
                % Data Layers: Select Data Layer
                app.showSelectDataLayer(app.responseDL)
                
                % Data Layers: Monthly Flux
                app.showDataLayersMonthlyFlux(app.responseDL)
                
                % Data Layers: Hourly Shade
                app.showDataLayersHourlyShade(app.responseDL)
            end
              
            % close progress bar
            close(progressBar)
        end

        % Value changed function: SelectDataLayerDropDown
        function SelectDataLayerDropDownValueChanged(app, event)
            value = app.SelectDataLayerDropDown.Value;
            
            switch value
                case "Annual Flux"
                    app.LayerType = "annualflux";
                case "DSM"
                    app.LayerType = "dsm";
                case "Roof Mask"
                    app.LayerType = "mask";
                otherwise
                    app.LayerType = "annualflux";
            end

            % progress bar
            fig = app.UIFigure;
            progressBar = uiprogressdlg(fig,'Title','Analysing data ...',...
                'Indeterminate','on');
            drawnow

            % FETCH DL DATA
            foundDL = app.fetchDL();
            
            if foundDL
                % Data Layers: Select Data Layer
                app.showSelectDataLayer(app.responseDL)
            end
              
            % close progress bar
            close(progressBar)
        end

        % Value changed function: SelectMonthDropDown
        function SelectMonthDropDownValueChanged(app, event)
            value = app.SelectMonthDropDown.Value;
            
            switch value
                case "January"
                    app.SelectMonthMonthlyFlux = 1;
                case "February"
                    app.SelectMonthMonthlyFlux = 2;
                case "March"
                    app.SelectMonthMonthlyFlux = 3;
                case "April"
                    app.SelectMonthMonthlyFlux = 4;
                case "May"
                    app.SelectMonthMonthlyFlux = 5;
                case "June"
                    app.SelectMonthMonthlyFlux = 6;
                case "July"
                    app.SelectMonthMonthlyFlux = 7;
                case "August"
                    app.SelectMonthMonthlyFlux = 8;
                case "September"
                    app.SelectMonthMonthlyFlux = 9;
                case "October"
                    app.SelectMonthMonthlyFlux = 10;
                case "November"
                    app.SelectMonthMonthlyFlux = 11;
                case "December"
                    app.SelectMonthMonthlyFlux = 12;
                otherwise
                    app.SelectMonthMonthlyFlux = 7;
            end

            % progress bar
            fig = app.UIFigure;
            progressBar = uiprogressdlg(fig,'Title','Analysing data ...',...
                'Indeterminate','on');
            drawnow
            
            %
            % geoTiffURL = create_data_layers_url(app.GM_API_KEY, app.SelectedLatitude,...
            %     app.SelectedLongitude, app.geoTiffZoomLevel);
            % 
            % % Get response
            % try
            %     response = webread(geoTiffURL);
            % catch
            %     uialert(app.UIFigure, 'Unable to find data', 'Error');
            %     return
            % end
            % 
            % % Data Layers
            % 
            % % Parse the response JSON if it's a string
            % if ischar(response)
            %     response = jsondecode(response);
            % end
            % FETCH DL DATA
            foundDL = app.fetchDL();
            
            if foundDL
                % Data Layers: Monthly Flux
                app.showDataLayersMonthlyFlux(app.responseDL)   
            end
              
            % close progress bar
            close(progressBar)
        end

        % Value changed function: SelectMonthDropDownHourly
        function SelectMonthDropDownHourlyValueChanged(app, event)
            value = app.SelectMonthDropDownHourly.Value;
            
            days_28 = string(1:28);
            days_30 = string(1:30);
            days_31 = string(1:31);

            switch value
                case "January"
                    app.SelectMonthHourlyShade = 1;
                    app.SelectDayDropDownHourly.Items = days_31;
                case "February"
                    app.SelectMonthHourlyShade = 2;
                    app.SelectDayDropDownHourly.Items = days_28;
                case "March"
                    app.SelectMonthHourlyShade = 3;
                    app.SelectDayDropDownHourly.Items = days_31;
                case "April"
                    app.SelectMonthHourlyShade = 4;
                    app.SelectDayDropDownHourly.Items = days_30;
                case "May"
                    app.SelectMonthHourlyShade = 5;
                    app.SelectDayDropDownHourly.Items = days_31;
                case "June"
                    app.SelectMonthHourlyShade = 6;
                    app.SelectDayDropDownHourly.Items = days_30;
                case "July"
                    app.SelectMonthHourlyShade = 7;
                    app.SelectDayDropDownHourly.Items = days_31;
                case "August"
                    app.SelectMonthHourlyShade = 8;
                    app.SelectDayDropDownHourly.Items = days_31;
                case "September"
                    app.SelectMonthHourlyShade = 9;
                    app.SelectDayDropDownHourly.Items = days_30;
                case "October"
                    app.SelectMonthHourlyShade = 10;
                    app.SelectDayDropDownHourly.Items = days_31;
                case "November"
                    app.SelectMonthHourlyShade = 11;
                    app.SelectDayDropDownHourly.Items = days_30;
                case "December"
                    app.SelectMonthHourlyShade = 12;
                    app.SelectDayDropDownHourly.Items = days_31;
                otherwise
                    app.SelectMonthHourlyShade = days_31;
            end
        end

        % Button pushed function: GetHourlyShadeButton
        function GetHourlyShadeButtonPushed(app, event)
             % progress bar
            fig = app.UIFigure;
            progressBar = uiprogressdlg(fig,'Title','Analysing data ...',...
                'Indeterminate','on');
            drawnow
            
            % FETCH DL DATA
            foundDL = app.fetchDL();
            
            if foundDL
                % Data Layers: Hourly Shade
                app.showDataLayersHourlyShade(app.responseDL)
            end
              
            % close progress bar
            close(progressBar)
        end

        % Value changed function: SelectDayDropDownHourly
        function SelectDayDropDownHourlyValueChanged(app, event)
            app.SelectDayHourlyShade = str2double(app.SelectDayDropDownHourly.Value);            
        end

        % Value changed function: SelectHourDropDownHourly
        function SelectHourDropDownHourlyValueChanged(app, event)
            app.SelectHourHourlyShade = str2double(app.SelectHourDropDownHourly.Value);            
        end

        % Value changed function: SwitchRoofMaskMonthly
        function SwitchRoofMaskMonthlyValueChanged(app, event)
            value = app.SwitchRoofMaskMonthly.Value;
            
            if strcmp(value, 'On')
                app.RoofMaskMonthly = true;
            elseif strcmp(value, 'Off')
                app.RoofMaskMonthly = false;
            else
                app.RoofMaskMonthly = false;
            end

            % progress bar
            fig = app.UIFigure;
            progressBar = uiprogressdlg(fig,'Title','Analysing data ...',...
                'Indeterminate','on');
            drawnow
            
            % FETCH DL DATA
            foundDL = app.fetchDL();
            
            if foundDL
                % Data Layers: Monthly Flux
                app.showDataLayersMonthlyFlux(app.responseDL)   
            end
              
            % close progress bar
            close(progressBar)
        end

        % Value changed function: SwitchRoofMaskHourly
        function SwitchRoofMaskHourlyValueChanged(app, event)
            value = app.SwitchRoofMaskHourly.Value;
            
            if strcmp(value, 'On')
                app.RoofMaskHourly = true;
            elseif strcmp(value, 'Off')
                app.RoofMaskHourly = false;
            else
                app.RoofMaskHourly = false;
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            % Get the current timestamp in a readable format
            timestamp = datetime('now','Format','yyyy-MM-dd_HH-mm-ss');
            
            % Define filenames
            filenameDL = sprintf('%s_data_layers.json', timestamp);
            filenameBI = sprintf('%s_building_insights.json', timestamp);
            
            % Initialize success flags
            exportDLSuccess = false;
            exportBISuccess = false;
            
            % Convert responseDL to JSON and save to file
            try
                jsonDL = jsonencode(app.responseDL); % Encode to JSON
                filepathDL = fullfile(pwd, filenameDL); % Save in the current directory
                fid = fopen(filepathDL, 'w'); % Open file for writing
                if fid == -1
                    error('Could not open file for writing: %s', filepathDL);
                end
                fwrite(fid, jsonDL, 'char'); % Write JSON data
                fclose(fid); % Close the file
                disp(['Exported Data Layers to ', filepathDL]);
                exportDLSuccess = true; % Mark success
            catch ME
                disp('Failed to export Data Layers.');
                disp(ME.message);
            end
            
            % Convert responseBI to JSON and save to file
            try
                jsonBI = jsonencode(app.responseBI); % Encode to JSON
                filepathBI = fullfile(pwd, filenameBI); % Save in the current directory
                fid = fopen(filepathBI, 'w'); % Open file for writing
                if fid == -1
                    error('Could not open file for writing: %s', filepathBI);
                end
                fwrite(fid, jsonBI, 'char'); % Write JSON data
                fclose(fid); % Close the file
                disp(['Exported Building Insights to ', filepathBI]);
                exportBISuccess = true; % Mark success
            catch ME
                disp('Failed to export Building Insights.');
                disp(ME.message);
            end
            
            % Display success alert
            if exportDLSuccess && exportBISuccess
                uialert(app.UIFigure, 'Both Data Layers and Building Insights have been successfully exported!', ...
                    'Export Successful', 'Icon', 'success');
            elseif exportDLSuccess
                uialert(app.UIFigure, 'Data Layers have been successfully exported, but Building Insights export failed.', ...
                    'Partial Export', 'Icon', 'warning');
            elseif exportBISuccess
                uialert(app.UIFigure, 'Building Insights have been successfully exported, but Data Layers export failed.', ...
                    'Partial Export', 'Icon', 'warning');
            else
                uialert(app.UIFigure, 'Both exports failed. Please check the error logs for details.', ...
                    'Export Failed', 'Icon', 'error');
            end
        end

        % Value changed function: PanelCapacityDropDown
        function PanelCapacityDropDownValueChanged(app, event)
            value = app.PanelCapacityDropDown.Value;
            
            switch value
                case "200 W"
                    app.SelectedPanelCapacity = 200;
                case "300 W"
                    app.SelectedPanelCapacity = 300;
                case "400 W"
                    app.SelectedPanelCapacity = 400;
                case "500 W"
                    app.SelectedPanelCapacity = 500;
                case "600 W"
                    app.SelectedPanelCapacity = 600;
                case "700 W"
                    app.SelectedPanelCapacity = 700;
                case "800 W"
                    app.SelectedPanelCapacity = 800;
                otherwise
                    app.SelectedPanelCapacity = 400;
            end

            % progress bar
            fig = app.UIFigure;
            progressBar = uiprogressdlg(fig,'Title','Analysing data ...',...
                'Indeterminate','on');
            drawnow

            % Reanalyse energy production related parameters
            app.addTableDataBI(app.responseBI)
            app.addSolarPanelsMapsAndTables(app.responseBI)
            app.addSolarPanelConfigsPlot(app.responseBI)

            % close progress bar
            close(progressBar)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % Check if the Play button was already clicked
            if strcmp(app.PlayButton.Icon, 'play.png')
                % Set the button to 'Stop' mode
                app.PlayButton.Icon = 'stop.png';
                
                % Specify the video file path
                videoFile = 'hourly_shade.mp4'; % Use your video file name

                % Create a VideoReader object
                videoObj = VideoReader(videoFile);

                % Initialize the image display in UIAxes if not already done
                if isempty(app.hImage) || ~isvalid(app.hImage)
                    % Read the first frame
                    frame = readFrame(videoObj);

                    % Display the first frame in the UIAxes
                    % Map the frame to the entire UIAxes area
                    app.hImage = imshow(frame, 'Parent', app.UIAxesHourlyShadeAnimation);

                    % Adjust UIAxes properties
                    app.UIAxesHourlyShadeAnimation.XTick = [];
                    app.UIAxesHourlyShadeAnimation.YTick = [];
                    app.UIAxesHourlyShadeAnimation.Box = 'on';
                end

                % Play video frame-by-frame
                videoObj.CurrentTime = 0; % Restart video from the beginning
                while hasFrame(videoObj) && strcmp(app.PlayButton.Icon, 'stop.png')
                    frame = readFrame(videoObj); % Read the next frame
                    app.hImage.CData = frame; % Update the image data
                    pause(1 / videoObj.FrameRate); % Pause to match the video frame rate
                end
                
                % Reset button text after playback stops
                app.PlayButton.Icon = 'play.png';
                % delete(shade_filename);
            else
                % If button is in 'Stop' mode, reset it to 'Play'
                app.PlayButton.Icon = 'play.png';
            end
        end

        % Value changed function: NumberofPanelsSlider
        function NumberofPanelsSliderValueChanged(app, event)
            app.NumberOfPanels = round(app.NumberofPanelsSlider.Value,0); % Snap to the nearest integer
            app.NumberofPanelsSlider.Value = app.NumberOfPanels; % Update slider to the snapped value
            app.NumberofPanelsEditField.Value = app.NumberOfPanels; % Sync with edit field
            
            % progress bar
            fig = app.UIFigure;
            progressBar = uiprogressdlg(fig,'Title','Analysing data ...',...
                'Indeterminate','on');
            drawnow

            % Update panel locations for the selected array
            app.addSolarPanelsMapsAndTables(app.responseBI) 
            app.addSolarPanelConfigsPlot(app.responseBI)

            close(progressBar)
        end

        % Value changed function: NumberofPanelsEditField
        function NumberofPanelsEditFieldValueChanged(app, event)
            enteredValue = app.NumberofPanelsEditField.Value; % Get the value from the edit field

            % Check if the entered value is an integer
            if mod(enteredValue, 1) ~= 0
                % The value is not an integer
                uialert(app.UIFigure, 'Please enter a whole number.', 'Invalid Input');
                app.NumberofPanelsEditField.Value = app.NumberofPanelsSlider.Value; % Reset to current slider value
                return;
            end
        
            % Check if the entered value is within the slider range
            if enteredValue >= app.NumberofPanelsSlider.Limits(1) && enteredValue <= app.NumberofPanelsSlider.Limits(2)
                % The value is valid
                app.NumberofPanelsSlider.Value = enteredValue; % Update the slider value
                app.NumberOfPanels = round(enteredValue,0);

                % progress bar
                fig = app.UIFigure;
                progressBar = uiprogressdlg(fig,'Title','Analysing data ...',...
                    'Indeterminate','on');
                drawnow

                % Update panel locations for the selected array
                app.addSolarPanelsMapsAndTables(app.responseBI) 
                app.addSolarPanelConfigsPlot(app.responseBI)

                close(progressBar)
            else
                % The value is out of range
                uialert(app.UIFigure, 'Value is out of range. Please enter a valid number.', 'Invalid Input');
                app.NumberofPanelsEditField.Value = app.NumberofPanelsSlider.Value; % Reset to current slider value
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.NumberOfPanels = round(app.MaxNumberOfPanels,0);
            app.NumberofPanelsSlider.Value = app.MaxNumberOfPanels; % Update the slider value
            app.NumberofPanelsEditField.Value = app.MaxNumberOfPanels;

            % progress bar
            fig = app.UIFigure;
            progressBar = uiprogressdlg(fig,'Title','Analysing data ...',...
                'Indeterminate','on');
            drawnow

            % Update panel locations for the max array
            app.addSolarPanelsMapsAndTables(app.responseBI) 
            app.addSolarPanelConfigsPlot(app.responseBI)

            close(progressBar)
        end

        % Selection changed function: OptimiseButtonGroup
        function OptimiseButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptimiseButtonGroup.SelectedObject.Text;
            
            if strcmp(selectedButton, "No")
                app.OptimiseSelection = "No";
            elseif strcmp(selectedButton, "Yes")
                app.OptimiseSelection = "Yes";
            else
                app.OptimiseSelection = "No";
            end
        end

        % Value changed function: NumberofSegmentsbyIndexSlider
        function NumberofSegmentsbyIndexSliderValueChanged(app, event)
            app.SegmentNumber = round(app.NumberofSegmentsbyIndexSlider.Value,0);
            app.NumberofSegmentsbyIndexSlider.Value = app.SegmentNumber;
            app.SegmentIndexEditField.Value = app.SegmentNumber;

            % progress bar
            fig = app.UIFigure;
            progressBar = uiprogressdlg(fig,'Title','Analysing data ...',...
                'Indeterminate','on');
            drawnow

            % Update panel locations for the selected array
            app.addSolarPanelsMapsAndTables(app.responseBI) 
            % app.addSolarPanelConfigsPlot(app.responseBI)

            close(progressBar)
        end

        % Value changed function: SegmentIndexEditField
        function SegmentIndexEditFieldValueChanged(app, event)
            enteredValue = app.SegmentIndexEditField.Value;

            % Check if the entered value is an integer
            if mod(enteredValue, 1) ~= 0
                % The value is not an integer
                uialert(app.UIFigure, 'Please enter a whole number.', 'Invalid Input');
                app.SegmentIndexEditField.Value = app.NumberofSegmentsbyIndexSlider.Value; % Reset to current slider value
                return;
            end

            % Check if the entered value is within the slider range
            if enteredValue >= app.NumberofSegmentsbyIndexSlider.Limits(1) && enteredValue <= app.NumberofSegmentsbyIndexSlider.Limits(2)
                % The value is valid
                app.NumberofSegmentsbyIndexSlider.Value = enteredValue; % Update the slider value
                app.SegmentNumber = round(enteredValue,0);

                % progress bar
                fig = app.UIFigure;
                progressBar = uiprogressdlg(fig,'Title','Analysing data ...',...
                    'Indeterminate','on');
                drawnow

                % Update panel locations for the selected array
                app.addSolarPanelsMapsAndTables(app.responseBI) 
                % app.addSolarPanelConfigsPlot(app.responseBI)

                close(progressBar)
            else
                % The value is out of range
                uialert(app.UIFigure, 'Value is out of range. Please enter a valid number.', 'Invalid Input');
                app.SegmentIndexEditField.Value = app.NumberofSegmentsbyIndexSlider.Value; % Reset to current slider value
            end
            
        end

        % Button pushed function: SegmentIndexRefreshButton
        function SegmentIndexRefreshButtonPushed(app, event)
            % SegmentNumber, MaxSegmentNumber
            app.SegmentNumber = round(app.MaxSegmentNumber);
            app.NumberofSegmentsbyIndexSlider.Value = app.MaxSegmentNumber;
            app.SegmentIndexEditField.Value = app.MaxSegmentNumber;
            
            % progress bar
            fig = app.UIFigure;
            progressBar = uiprogressdlg(fig,'Title','Analysing data ...',...
                'Indeterminate','on');
            drawnow

            % Update roof segment locations for max array
            app.addSolarPanelsMapsAndTables(app.responseBI) 
            % app.addSolarPanelConfigsPlot(app.responseBI)

            close(progressBar)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sun-logo.png');
            app.PVPROLabelHome = uilabel(app.HomeTab);
            app.PVPROLabelHome.Text = 'PV PRO';
            app.LocationTab = uitab(app.TabGroup);
            app.LocationTab.Title = 'Location';
            app.UIAxesGoogleMap = uiaxes(app.LocationTab);
            app.UIAxesGoogleMap.XTick = [];
            app.UIAxesGoogleMap.YTick = [];
            app.UIAxesGoogleMap.ZTick = [];
            app.ZoomLevelSliderLabel = uilabel(app.LocationTab);
            app.ZoomLevelSliderLabel.Text = 'Zoom Level';
            app.ZoomLevelSlider = uislider(app.LocationTab);
            app.ZoomLevelSlider.Limits = [1 19];
            app.ZoomLevelSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19];
            app.ZoomLevelSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19'};
            app.ZoomLevelSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomLevelSliderValueChanged, true);
            app.ZoomLevelSlider.MinorTicks = [];
            app.ZoomLevelSlider.Value = 1;
            app.FormattedAddressLabel = uilabel(app.LocationTab);
            app.FormattedAddressLabel.Text = '';
            app.SearchLocationPanel = uipanel(app.LocationTab);
            app.SearchLocationPanel.Title = 'Search Location';
            app.AnalyseButton = uibutton(app.SearchLocationPanel, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            app.FindButton = uibutton(app.SearchLocationPanel, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Text = 'Find';
            app.LatitudeEditField = uieditfield(app.SearchLocationPanel, 'numeric');
            app.LatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudeEditFieldValueChanged, true);
            app.LongitudeEditField = uieditfield(app.SearchLocationPanel, 'numeric');
            app.LongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudeEditFieldValueChanged, true);
            app.LongitudeSliderLabel = uilabel(app.SearchLocationPanel);
            app.LongitudeSliderLabel.Text = 'Longitude';
            app.LongitudeSlider = uislider(app.SearchLocationPanel);
            app.LongitudeSlider.Limits = [-180 180];
            app.LongitudeSlider.ValueChangedFcn = createCallbackFcn(app, @LongitudeSliderValueChanged, true);
            app.LatitudeSliderLabel = uilabel(app.SearchLocationPanel);
            app.LatitudeSliderLabel.Text = 'Latitude';
            app.LatitudeSlider = uislider(app.SearchLocationPanel);
            app.LatitudeSlider.Limits = [-90 90];
            app.LatitudeSlider.ValueChangedFcn = createCallbackFcn(app, @LatitudeSliderValueChanged, true);
            app.EnterstreetaddressEditField = uieditfield(app.SearchLocationPanel, 'text');
            app.EnterstreetaddressEditField.Placeholder = 'Enter street address';
            app.ExportDataButton = uibutton(app.SearchLocationPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Visible = 'off';
            app.ExportDataButton.Text = 'Export Data';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisDetailsTab = uitabgroup(app.AnalysisTab);
            app.DataLayersTab = uitab(app.AnalysisDetailsTab);
            app.DataLayersTab.Title = 'Data Layers';
            app.DataLayersDetailsTab = uitabgroup(app.DataLayersTab);
            app.GeneralTab = uitab(app.DataLayersDetailsTab);
            app.GeneralTab.Title = 'General';
            app.UIAxesAnnualFlux = uiaxes(app.GeneralTab);
            app.UIAxesAnnualFlux.XTick = [];
            app.UIAxesAnnualFlux.YTick = [];
            app.UIAxesAnnualFlux.Visible = 'off';
            app.UIAxesMask = uiaxes(app.GeneralTab);
            app.UIAxesMask.XTick = [];
            app.UIAxesMask.YTick = [];
            app.UIAxesMask.Visible = 'off';
            app.UIAxesDSM = uiaxes(app.GeneralTab);
            app.UIAxesDSM.XTick = [];
            app.UIAxesDSM.YTick = [];
            app.UIAxesDSM.Visible = 'off';
            app.UIAxesRGB = uiaxes(app.GeneralTab);
            app.UIAxesRGB.XTick = [];
            app.UIAxesRGB.YTick = [];
            app.UIAxesRGB.Visible = 'off';
            app.ViewDataLayerTab = uitab(app.DataLayersDetailsTab);
            app.ViewDataLayerTab.Title = 'View Data Layer';
            app.UIAxesDLDistribution = uiaxes(app.ViewDataLayerTab);
            app.UIAxesDLDistribution.XTick = [];
            app.UIAxesDLDistribution.YTick = [];
            app.UIAxesDLDistribution.Visible = 'off';
            app.UIAxesDLContour = uiaxes(app.ViewDataLayerTab);
            app.UIAxesDLContour.XTick = [];
            app.UIAxesDLContour.YTick = [];
            app.UIAxesDLContour.Visible = 'off';
            app.UIAxesDL3D = uiaxes(app.ViewDataLayerTab);
            app.UIAxesDL3D.XTick = [];
            app.UIAxesDL3D.YTick = [];
            app.UIAxesDL3D.Visible = 'off';
            app.UIAxesDLRaw = uiaxes(app.ViewDataLayerTab);
            app.UIAxesDLRaw.XTick = [];
            app.UIAxesDLRaw.YTick = [];
            app.UIAxesDLRaw.Visible = 'off';
            app.SelectDataPanelDL = uipanel(app.ViewDataLayerTab);
            app.SelectDataPanelDL.Title = 'Select Data';
            app.SelectDataLayerDropDownLabel = uilabel(app.SelectDataPanelDL);
            app.SelectDataLayerDropDownLabel.Text = 'Select Data Layer';
            app.SelectDataLayerDropDown = uidropdown(app.SelectDataPanelDL);
            app.SelectDataLayerDropDown.Items = {'Annual Flux', 'DSM', 'Roof Mask'};
            app.SelectDataLayerDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDataLayerDropDownValueChanged, true);
            app.SelectDataLayerDropDown.Visible = 'off';
            app.SelectDataLayerDropDown.Value = 'Annual Flux';
            app.StatsPanelDL = uipanel(app.ViewDataLayerTab);
            app.StatsPanelDL.Title = 'Stats';
            app.MinLabel = uilabel(app.StatsPanelDL);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.StatsPanelDL);
            app.MaxLabel.Text = 'Max';
            app.MeanLabel = uilabel(app.StatsPanelDL);
            app.MeanLabel.Text = 'Mean';
            app.UnitLabel = uilabel(app.StatsPanelDL);
            app.UnitLabel.Text = 'Unit';
            app.MonthlyFluxTab = uitab(app.DataLayersDetailsTab);
            app.MonthlyFluxTab.Title = 'Monthly Flux';
            app.UIAxesMonthlyDistribution = uiaxes(app.MonthlyFluxTab);
            app.UIAxesMonthlyDistribution.XTick = [];
            app.UIAxesMonthlyDistribution.YTick = [];
            app.UIAxesMonthlyDistribution.Visible = 'off';
            app.UIAxesMonthlyContour = uiaxes(app.MonthlyFluxTab);
            app.UIAxesMonthlyContour.XTick = [];
            app.UIAxesMonthlyContour.YTick = [];
            app.UIAxesMonthlyContour.Visible = 'off';
            app.UIAxesMonthly3D = uiaxes(app.MonthlyFluxTab);
            app.UIAxesMonthly3D.XTick = [];
            app.UIAxesMonthly3D.YTick = [];
            app.UIAxesMonthly3D.Visible = 'off';
            app.UIAxesMonthlyFlux = uiaxes(app.MonthlyFluxTab);
            app.UIAxesMonthlyFlux.XTick = [];
            app.UIAxesMonthlyFlux.YTick = [];
            app.UIAxesMonthlyFlux.Visible = 'off';
            app.SelectDatePanelMonthly = uipanel(app.MonthlyFluxTab);
            app.SelectDatePanelMonthly.Title = 'Select Date';
            app.SelectMonthDropDownLabel = uilabel(app.SelectDatePanelMonthly);
            app.SelectMonthDropDownLabel.Text = 'Select Month';
            app.SelectMonthDropDown = uidropdown(app.SelectDatePanelMonthly);
            app.SelectMonthDropDown.Items = {'January', 'February', 'March', 'April', 'May', 'June', 'July', 'August', 'September', 'October', 'November', 'December'};
            app.SelectMonthDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectMonthDropDownValueChanged, true);
            app.SelectMonthDropDown.Visible = 'off';
            app.SelectMonthDropDown.Value = 'January';
            app.SwitchRoofMaskMonthly = uiswitch(app.SelectDatePanelMonthly, 'slider');
            app.SwitchRoofMaskMonthly.ValueChangedFcn = createCallbackFcn(app, @SwitchRoofMaskMonthlyValueChanged, true);
            app.SwitchRoofMaskMonthly.Visible = 'off';
            app.RoofmaskLabelMonthly = uilabel(app.SelectDatePanelMonthly);
            app.RoofmaskLabelMonthly.Text = 'Roof mask:';
            app.StatsPanelMonthly = uipanel(app.MonthlyFluxTab);
            app.StatsPanelMonthly.Title = 'Stats';
            app.UnitLabelMonthly = uilabel(app.StatsPanelMonthly);
            app.UnitLabelMonthly.Text = 'Unit';
            app.MeanLabelMonthly = uilabel(app.StatsPanelMonthly);
            app.MeanLabelMonthly.Text = 'Mean';
            app.MaxLabelMonthly = uilabel(app.StatsPanelMonthly);
            app.MaxLabelMonthly.Text = 'Max';
            app.MinLabelMonthly = uilabel(app.StatsPanelMonthly);
            app.MinLabelMonthly.Text = 'Min';
            app.HourlyShadeTab = uitab(app.DataLayersDetailsTab);
            app.HourlyShadeTab.Title = 'Hourly Shade';
            app.UIAxesHourlyShadeDistribution = uiaxes(app.HourlyShadeTab);
            app.UIAxesHourlyShadeDistribution.XTick = [];
            app.UIAxesHourlyShadeDistribution.YTick = [];
            app.UIAxesHourlyShadeDistribution.Visible = 'off';
            app.UIAxesHourlyShadeVisibility = uiaxes(app.HourlyShadeTab);
            app.UIAxesHourlyShadeVisibility.XTick = [];
            app.UIAxesHourlyShadeVisibility.YTick = [];
            app.UIAxesHourlyShadeVisibility.Visible = 'off';
            app.UIAxesHourlyShadeData = uiaxes(app.HourlyShadeTab);
            app.UIAxesHourlyShadeData.XTick = [];
            app.UIAxesHourlyShadeData.YTick = [];
            app.UIAxesHourlyShadeData.Visible = 'off';
            app.UIAxesHourlyShadeAnimation = uiaxes(app.HourlyShadeTab);
            title(app.UIAxesHourlyShadeAnimation, 'Hourly Shade Animation')
            app.UIAxesHourlyShadeAnimation.XTick = [];
            app.UIAxesHourlyShadeAnimation.YTick = [];
            app.UIAxesHourlyShadeAnimation.Visible = 'off';
            app.SelectDatePanelHourly = uipanel(app.HourlyShadeTab);
            app.SelectDatePanelHourly.Title = 'Select Date';
            app.SelectMonthDropDown_2Label = uilabel(app.SelectDatePanelHourly);
            app.SelectMonthDropDown_2Label.Text = 'Select Month';
            app.SelectMonthDropDownHourly = uidropdown(app.SelectDatePanelHourly);
            app.SelectMonthDropDownHourly.Items = {};
            app.SelectMonthDropDownHourly.ValueChangedFcn = createCallbackFcn(app, @SelectMonthDropDownHourlyValueChanged, true);
            app.SelectMonthDropDownHourly.Visible = 'off';
            app.SelectMonthDropDownHourly.Value = {};
            app.SelectMonthDropDown_2Label_2 = uilabel(app.SelectDatePanelHourly);
            app.SelectMonthDropDown_2Label_2.Text = 'Select Day';
            app.SelectDayDropDownHourly = uidropdown(app.SelectDatePanelHourly);
            app.SelectDayDropDownHourly.Items = {};
            app.SelectDayDropDownHourly.ValueChangedFcn = createCallbackFcn(app, @SelectDayDropDownHourlyValueChanged, true);
            app.SelectDayDropDownHourly.Visible = 'off';
            app.SelectDayDropDownHourly.Value = {};
            app.SelectMonthDropDown_2Label_3 = uilabel(app.SelectDatePanelHourly);
            app.SelectMonthDropDown_2Label_3.Text = 'Select  Hour';
            app.SelectHourDropDownHourly = uidropdown(app.SelectDatePanelHourly);
            app.SelectHourDropDownHourly.Items = {};
            app.SelectHourDropDownHourly.ValueChangedFcn = createCallbackFcn(app, @SelectHourDropDownHourlyValueChanged, true);
            app.SelectHourDropDownHourly.Visible = 'off';
            app.SelectHourDropDownHourly.Value = {};
            app.GetHourlyShadeButton = uibutton(app.SelectDatePanelHourly, 'push');
            app.GetHourlyShadeButton.ButtonPushedFcn = createCallbackFcn(app, @GetHourlyShadeButtonPushed, true);
            app.GetHourlyShadeButton.Visible = 'off';
            app.GetHourlyShadeButton.Text = 'Get Hourly Shade';
            app.SwitchRoofMaskHourly = uiswitch(app.SelectDatePanelHourly, 'slider');
            app.SwitchRoofMaskHourly.ValueChangedFcn = createCallbackFcn(app, @SwitchRoofMaskHourlyValueChanged, true);
            app.SwitchRoofMaskHourly.Visible = 'off';
            app.RoofmaskLabelHourly = uilabel(app.SelectDatePanelHourly);
            app.RoofmaskLabelHourly.Text = 'Roof mask:';
            app.PlayButton = uibutton(app.HourlyShadeTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'play.png');
            app.PlayButton.Visible = 'off';
            app.PlayButton.Text = '';
            app.SelectZoomTab = uitabgroup(app.DataLayersTab);
            app.ZoomLevelTab = uitab(app.SelectZoomTab);
            app.ZoomLevelTab.Title = 'Zoom Level';
            app.SelectZoomLevelDropDownLabel = uilabel(app.ZoomLevelTab);
            app.SelectZoomLevelDropDownLabel.Text = 'Select Zoom Level';
            app.SelectZoomLevelDropDown = uidropdown(app.ZoomLevelTab);
            app.SelectZoomLevelDropDown.Items = {};
            app.SelectZoomLevelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectZoomLevelDropDownValueChanged, true);
            app.SelectZoomLevelDropDown.Visible = 'off';
            app.SelectZoomLevelDropDown.Value = {};
            app.DLLocationLabel = uilabel(app.ZoomLevelTab);
            app.LatLngLabel = uilabel(app.ZoomLevelTab);
            app.LatLngLabel.Text = 'Label2';
            app.BuildingInsightsTab = uitab(app.AnalysisDetailsTab);
            app.BuildingInsightsTab.Title = 'Building Insights';
            app.TabGroupBI = uitabgroup(app.BuildingInsightsTab);
            app.GeneralTabBI = uitab(app.TabGroupBI);
            app.GeneralTabBI.Title = 'General';
            app.TabGroupWholeRoofBI = uitabgroup(app.GeneralTabBI);
            app.WholeRoofTabBI = uitab(app.TabGroupWholeRoofBI);
            app.WholeRoofTabBI.Title = 'Whole Roof';
            app.TabGroupWholeRoofLocationsBI = uitabgroup(app.WholeRoofTabBI);
            app.PanelLocationsTab = uitab(app.TabGroupWholeRoofLocationsBI);
            app.PanelLocationsTab.Title = 'Panel Locations';
            app.PanelLocationsandEnergyProductionkWhYear = uipanel(app.PanelLocationsTab);
            app.PanelLocationsandEnergyProductionkWhYear.Title = 'Panel Locations and Energy Production (kWh/Year)';
            app.NumberofPanelsSliderLabel = uilabel(app.PanelLocationsTab);
            app.NumberofPanelsSliderLabel.Text = 'Number of Panels';
            app.NumberofPanelsSlider = uislider(app.PanelLocationsTab);
            app.NumberofPanelsSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofPanelsSliderValueChanged, true);
            app.NumberofPanelsSlider.Visible = 'off';
            app.NumberofPanelsEditField = uieditfield(app.PanelLocationsTab, 'numeric');
            app.NumberofPanelsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPanelsEditFieldValueChanged, true);
            app.NumberofPanelsEditField.Visible = 'off';
            app.EnergyProductionkWhyearEditFieldLabel = uilabel(app.PanelLocationsTab);
            app.EnergyProductionkWhyearEditFieldLabel.Text = 'Energy Production (kWh/year)';
            app.EnergyProductionkWhyearEditField = uieditfield(app.PanelLocationsTab, 'numeric');
            app.EnergyProductionkWhyearEditField.Editable = 'off';
            app.EnergyProductionkWhyearEditField.Visible = 'off';
            app.PercentofMaxArrayEditFieldLabel = uilabel(app.PanelLocationsTab);
            app.PercentofMaxArrayEditFieldLabel.Text = 'Percent of Max Array (%)';
            app.PercentofMaxArrayEditField = uieditfield(app.PanelLocationsTab, 'numeric');
            app.PercentofMaxArrayEditField.Editable = 'off';
            app.PercentofMaxArrayEditField.Visible = 'off';
            app.RefreshButton = uibutton(app.PanelLocationsTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = fullfile(pathToMLAPP, 'reset.png');
            app.RefreshButton.Visible = 'off';
            app.RefreshButton.Text = '';
            app.OptimiseButtonGroup = uibuttongroup(app.PanelLocationsTab);
            app.OptimiseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptimiseButtonGroupSelectionChanged, true);
            app.OptimiseButtonGroup.Title = 'Optimise';
            app.OptimiseButtonGroup.Visible = 'off';
            app.NoButton = uiradiobutton(app.OptimiseButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.YesButton = uiradiobutton(app.OptimiseButtonGroup);
            app.YesButton.Text = 'Yes';
            app.QuantilesTab = uitab(app.TabGroupWholeRoofLocationsBI);
            app.QuantilesTab.Title = 'Quantiles';
            app.UIAxesSunshineQuantilesBI = uiaxes(app.QuantilesTab);
            title(app.UIAxesSunshineQuantilesBI, 'Title')
            xlabel(app.UIAxesSunshineQuantilesBI, 'X')
            ylabel(app.UIAxesSunshineQuantilesBI, 'Y')
            zlabel(app.UIAxesSunshineQuantilesBI, 'Z')
            app.UIAxesSunshineQuantilesBI.Visible = 'off';
            app.QuantilePercentageContributionPanel = uipanel(app.QuantilesTab);
            app.QuantilePercentageContributionPanel.Title = 'Quantile Percentage Contribution';
            app.UITableSunshineQuantilePctBI = uitable(app.QuantilePercentageContributionPanel);
            app.UITableSunshineQuantilePctBI.ColumnName = '';
            app.UITableSunshineQuantilePctBI.RowName = {};
            app.UITableSunshineQuantilePctBI.Visible = 'off';
            app.RoofSegmentsTabBI = uitab(app.TabGroupWholeRoofBI);
            app.RoofSegmentsTabBI.Title = 'Roof Segments';
            app.TabGroupRoofSegmentsParametersBI = uitabgroup(app.RoofSegmentsTabBI);
            app.LocationsTabRoofSegmentsBI = uitab(app.TabGroupRoofSegmentsParametersBI);
            app.LocationsTabRoofSegmentsBI.Title = 'Roof Segment Locations';
            app.RoofSegmentLocationsPanel = uipanel(app.LocationsTabRoofSegmentsBI);
            app.RoofSegmentLocationsPanel.Title = 'Roof Segment Locations';
            app.NumberofSegmentsbyIndexSliderLabel = uilabel(app.LocationsTabRoofSegmentsBI);
            app.NumberofSegmentsbyIndexSliderLabel.Text = {'Number of Segments'; 'by Index'};
            app.NumberofSegmentsbyIndexSlider = uislider(app.LocationsTabRoofSegmentsBI);
            app.NumberofSegmentsbyIndexSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofSegmentsbyIndexSliderValueChanged, true);
            app.NumberofSegmentsbyIndexSlider.Visible = 'off';
            app.SegmentIndexEditField = uieditfield(app.LocationsTabRoofSegmentsBI, 'numeric');
            app.SegmentIndexEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentIndexEditFieldValueChanged, true);
            app.SegmentIndexEditField.Visible = 'off';
            app.SegmentIndexRefreshButton = uibutton(app.LocationsTabRoofSegmentsBI, 'push');
            app.SegmentIndexRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentIndexRefreshButtonPushed, true);
            app.SegmentIndexRefreshButton.Icon = fullfile(pathToMLAPP, 'reset.png');
            app.SegmentIndexRefreshButton.Visible = 'off';
            app.SegmentIndexRefreshButton.Text = '';
            app.EnergyProductionkWhyearEditField_2Label = uilabel(app.LocationsTabRoofSegmentsBI);
            app.EnergyProductionkWhyearEditField_2Label.Text = 'Energy Production (kWh/year)';
            app.SegmentIndexEnergyProductionkWhyearEditField = uieditfield(app.LocationsTabRoofSegmentsBI, 'numeric');
            app.SegmentIndexEnergyProductionkWhyearEditField.Editable = 'off';
            app.SegmentIndexEnergyProductionkWhyearEditField.Visible = 'off';
            app.PercentofMaxArrayEditField_2Label = uilabel(app.LocationsTabRoofSegmentsBI);
            app.PercentofMaxArrayEditField_2Label.Text = 'Percent of Max Array (%)';
            app.SegmentIndexPercentofMaxArrayEditField = uieditfield(app.LocationsTabRoofSegmentsBI, 'numeric');
            app.SegmentIndexPercentofMaxArrayEditField.Editable = 'off';
            app.SegmentIndexPercentofMaxArrayEditField.Visible = 'off';
            app.ParametersTabBI = uitab(app.TabGroupRoofSegmentsParametersBI);
            app.ParametersTabBI.Title = 'Parameters';
            app.UIAxesRoofSegmentOrientationsBI = uiaxes(app.ParametersTabBI);
            title(app.UIAxesRoofSegmentOrientationsBI, 'Title')
            xlabel(app.UIAxesRoofSegmentOrientationsBI, 'X')
            ylabel(app.UIAxesRoofSegmentOrientationsBI, 'Y')
            zlabel(app.UIAxesRoofSegmentOrientationsBI, 'Z')
            app.UIAxesRoofSegmentOrientationsBI.Visible = 'off';
            app.UIAxesRoofSegmentAreaHeightBI = uiaxes(app.ParametersTabBI);
            title(app.UIAxesRoofSegmentAreaHeightBI, 'Title')
            xlabel(app.UIAxesRoofSegmentAreaHeightBI, 'X')
            ylabel(app.UIAxesRoofSegmentAreaHeightBI, 'Y')
            zlabel(app.UIAxesRoofSegmentAreaHeightBI, 'Z')
            app.UIAxesRoofSegmentAreaHeightBI.Visible = 'off';
            app.PanelDistributionTab = uitab(app.TabGroupRoofSegmentsParametersBI);
            app.PanelDistributionTab.Title = 'Panel Distribution';
            app.UIAxesPanelConfigsPanelsPerSegmentBI = uiaxes(app.PanelDistributionTab);
            app.UIAxesPanelConfigsPanelsPerSegmentBI.XTick = [];
            app.UIAxesPanelConfigsPanelsPerSegmentBI.YTick = [];
            app.UIAxesPanelConfigsPanelsPerSegmentBI.ZTick = [];
            app.UIAxesPanelConfigsPanelsPerSegmentBI.Visible = 'off';
            app.PanelConfigTabBI = uitab(app.TabGroupBI);
            app.PanelConfigTabBI.Title = 'Panel Config';
            app.TabGroupPanelConfigDetailsBI = uitabgroup(app.PanelConfigTabBI);
            app.EnergyProductionTabBI = uitab(app.TabGroupPanelConfigDetailsBI);
            app.EnergyProductionTabBI.Title = 'Energy Production';
            app.UIAxesPanelConfigsProductionPerPanelBI = uiaxes(app.EnergyProductionTabBI);
            title(app.UIAxesPanelConfigsProductionPerPanelBI, 'Title')
            xlabel(app.UIAxesPanelConfigsProductionPerPanelBI, 'X')
            ylabel(app.UIAxesPanelConfigsProductionPerPanelBI, 'Y')
            zlabel(app.UIAxesPanelConfigsProductionPerPanelBI, 'Z')
            app.UIAxesPanelConfigsProductionPerPanelBI.Visible = 'off';
            app.UIAxesPanelConfigsProductionTotalBI = uiaxes(app.EnergyProductionTabBI);
            title(app.UIAxesPanelConfigsProductionTotalBI, 'Title')
            xlabel(app.UIAxesPanelConfigsProductionTotalBI, 'X')
            ylabel(app.UIAxesPanelConfigsProductionTotalBI, 'Y')
            zlabel(app.UIAxesPanelConfigsProductionTotalBI, 'Z')
            app.UIAxesPanelConfigsProductionTotalBI.Visible = 'off';
            app.ParametersPanelConfigTabBI = uitab(app.TabGroupPanelConfigDetailsBI);
            app.ParametersPanelConfigTabBI.Title = 'Individual Panel Parameters';
            app.TabGroupPanelConfigStatsTableBI = uitabgroup(app.ParametersPanelConfigTabBI);
            app.StatisticsTab = uitab(app.TabGroupPanelConfigStatsTableBI);
            app.StatisticsTab.Title = 'Statistics';
            app.TabGroupArraySelectionStatistics = uitabgroup(app.StatisticsTab);
            app.MaxArrayPanelConfigsStatisticsTab = uitab(app.TabGroupArraySelectionStatistics);
            app.MaxArrayPanelConfigsStatisticsTab.Title = 'Max Array';
            app.UIAxesPanelConfigsEnergyPerPanelHistBI = uiaxes(app.MaxArrayPanelConfigsStatisticsTab);
            app.UIAxesPanelConfigsEnergyPerPanelHistBI.XTick = [];
            app.UIAxesPanelConfigsEnergyPerPanelHistBI.YTick = [];
            app.UIAxesPanelConfigsEnergyPerPanelHistBI.ZTick = [];
            app.UIAxesPanelConfigsEnergyPerPanelHistBI.Visible = 'off';
            app.MaxArrayIndividualPanelStatistics = uipanel(app.MaxArrayPanelConfigsStatisticsTab);
            app.MaxArrayIndividualPanelStatistics.Title = 'Max Array Individual Panel Statistics';
            app.MaxArrayIndividualPanelStatistics.Visible = 'off';
            app.UITableIndividualPanelsStatsBI = uitable(app.MaxArrayIndividualPanelStatistics);
            app.UITableIndividualPanelsStatsBI.ColumnName = '';
            app.UITableIndividualPanelsStatsBI.RowName = {};
            app.SelectedArrayPanelConfigsStatisticsTab = uitab(app.TabGroupArraySelectionStatistics);
            app.SelectedArrayPanelConfigsStatisticsTab.Title = 'Selected Array';
            app.UIAxesSelectedPanelsHist = uiaxes(app.SelectedArrayPanelConfigsStatisticsTab);
            app.UIAxesSelectedPanelsHist.XTick = [];
            app.UIAxesSelectedPanelsHist.YTick = [];
            app.UIAxesSelectedPanelsHist.ZTick = [];
            app.UIAxesSelectedPanelsHist.Visible = 'off';
            app.SelectedArrayIndividualPanelsStatistics = uipanel(app.SelectedArrayPanelConfigsStatisticsTab);
            app.SelectedArrayIndividualPanelsStatistics.Title = 'Selected Array Individual Panels Statistics';
            app.SelectedArrayIndividualPanelsStatistics.Visible = 'off';
            app.UITableSelectedPanels = uitable(app.SelectedArrayIndividualPanelsStatistics);
            app.UITableSelectedPanels.ColumnName = '';
            app.UITableSelectedPanels.RowName = {};
            app.IndividualPanelsTab = uitab(app.TabGroupPanelConfigStatsTableBI);
            app.IndividualPanelsTab.Title = 'Individual Panels';
            app.TabGroupArraySelectionIndividualPanels = uitabgroup(app.IndividualPanelsTab);
            app.MaxArrayTab = uitab(app.TabGroupArraySelectionIndividualPanels);
            app.MaxArrayTab.Title = 'Max Array';
            app.IndividualPanelsMaxArray = uipanel(app.MaxArrayTab);
            app.IndividualPanelsMaxArray.Title = 'Individual Panels (Max Array)';
            app.IndividualPanelsMaxArray.Visible = 'off';
            app.UITableIndividualPanelsBI = uitable(app.IndividualPanelsMaxArray);
            app.UITableIndividualPanelsBI.ColumnName = '';
            app.UITableIndividualPanelsBI.RowName = {};
            app.SelectedPanelsTab = uitab(app.TabGroupArraySelectionIndividualPanels);
            app.SelectedPanelsTab.Title = 'Selected Panels';
            app.IndividualPanelsSelectedArray = uipanel(app.SelectedPanelsTab);
            app.IndividualPanelsSelectedArray.Title = 'Individual Panels (Selected Array)';
            app.IndividualPanelsSelectedArray.Visible = 'off';
            app.UITableSelectedArray = uitable(app.IndividualPanelsSelectedArray);
            app.UITableSelectedArray.ColumnName = '';
            app.UITableSelectedArray.RowName = {};
            app.EconomicAnalysisinUSTab = uitab(app.TabGroupBI);
            app.EconomicAnalysisinUSTab.Title = 'Economic Analysis (in US)';
            app.ImageryInfoPanel = uipanel(app.BuildingInsightsTab);
            app.ImageryInfoPanel.Title = 'Imagery Info';
            app.UITableImageryInfo = uitable(app.ImageryInfoPanel);
            app.UITableImageryInfo.ColumnName = '';
            app.UITableImageryInfo.RowName = {};
            app.UITableImageryInfo.Visible = 'off';
            app.BuildingInfoPanel = uipanel(app.BuildingInsightsTab);
            app.BuildingInfoPanel.Title = 'Building Info';
            app.UITableBuildingInformation = uitable(app.BuildingInfoPanel);
            app.UITableBuildingInformation.ColumnName = '';
            app.UITableBuildingInformation.RowName = {};
            app.UITableBuildingInformation.Visible = 'off';
            app.WholeRoofInfoPanel = uipanel(app.BuildingInsightsTab);
            app.WholeRoofInfoPanel.Title = 'Whole Roof Info';
            app.UITableRoofStats = uitable(app.WholeRoofInfoPanel);
            app.UITableRoofStats.ColumnName = '';
            app.UITableRoofStats.RowName = {};
            app.UITableRoofStats.Visible = 'off';
            app.RoofSegmentInfoPanel = uipanel(app.BuildingInsightsTab);
            app.RoofSegmentInfoPanel.Title = 'Roof Segment Info';
            app.UITableRoofSegmentInfo = uitable(app.RoofSegmentInfoPanel);
            app.UITableRoofSegmentInfo.ColumnName = '';
            app.UITableRoofSegmentInfo.RowName = {};
            app.UITableRoofSegmentInfo.Visible = 'off';
            app.PanelInfo = uipanel(app.BuildingInsightsTab);
            app.PanelInfo.Title = 'Panel Info';
            app.UITablePanelStats = uitable(app.PanelInfo);
            app.UITablePanelStats.ColumnName = '';
            app.UITablePanelStats.RowName = {};
            app.UITablePanelStats.Visible = 'off';
            app.SolarPotentialPanel = uipanel(app.BuildingInsightsTab);
            app.SolarPotentialPanel.Title = 'Solar Potential';
            app.UITableSolarPotential = uitable(app.SolarPotentialPanel);
            app.UITableSolarPotential.ColumnName = '';
            app.UITableSolarPotential.RowName = {};
            app.UITableSolarPotential.Visible = 'off';
            app.PanelCapacityDropDownLabel = uilabel(app.BuildingInsightsTab);
            app.PanelCapacityDropDownLabel.Text = 'Panel Capacity';
            app.PanelCapacityDropDown = uidropdown(app.BuildingInsightsTab);
            app.PanelCapacityDropDown.ValueChangedFcn = createCallbackFcn(app, @PanelCapacityDropDownValueChanged, true);
            app.PanelCapacityDropDown.Visible = 'off';
            app.APICoverageTab = uitab(app.AnalysisDetailsTab);
            app.APICoverageTab.Title = 'API Coverage';
            app.APICoverageMap = uipanel(app.APICoverageTab);
            app.APICoverageMap.Title = 'API Coverage Map';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.ThLabelDescription = uilabel(app.AboutTab);
            app.ThLabelDescription.Text = {'DESCRIPTION'; 'This MATLAB application (PV PRO) allows the user to access data from the Google Maps Solar API, and use the data to design rooftop PV arrays. Google Maps '; 'API key is required to use the app. '; ''; 'SOLAR API'; 'According to Google, the Solar API covers more than 472 million buildings in over 40 countries (see the ''API Coverage'' tab inside the ''Analysis'' tab '; 'for an API coverage map), which is not an insignificant number. To be more specific, the API covers 472 million roofs all over the world.'; ''; 'The API accepts requests for three endpoints:'; ' - buildingInsights: This service endpoint returns insights about the location, dimensions, and solar potential of a building.'; ' - dataLayers: This service endpoint returns URLs for raw solar information datasets for an area surrounding a location.'; ' - geoTiff: This endpoint fetches rasters with encoded solar information, including a digital surface model, an aerial image, annual and monthly flux '; 'maps, and hourly shade.'; ''; 'The imagery quality can be HIGH, MEDIUM, or LOW:'; ''; ' - HIGH: Solar data is based on high-resolution (e.g., 10cm) DSM data, typically from low-altitude aerial imagery.'; ' - MEDIUM: Solar data is based on medium-resolution (e.g., 25cm) DSM data, typically from high-altitude aerial imagery.'; ' - LOW: Solar data is based on low-resolution (e.g., 30 cm or worse) DSM data, typically from satellite imagery.'; ''; 'USAGE'; 'The application can be used to design building-integrated PV systems (BIPV), or in this case PV arrays on rooftops.'; ''; 'In the ''Location'' tab the user can select a location either by searching for a street address, or by entering latitude and longitude information. If the API'; 'covers the searched location, the results are  presented in the ''Analysis'' tab. The results show both ''Data Layers'' images and also ''Building Insights'' data. '; ''; 'The ''Data Layers'' tab presents detailed information about e.g. annual flux, monthly flux, hourly shade and DSM (digital surface model).'; ''; 'The ''Building Insights'' tab presents e.g. rooftop information, solar panel and array location information, roof segment information with tables and graphs that show '; 'relevant statistics and parameters. The user can select and optimise a correctly sized PV array based on output data. The application does not currently cover'; 'the economic analysis that is available for locations in the US. If the service is made available outside US, it will probably be included in the next version of the '; 'application, otherwise, maybe not in the next version.'; ''; 'LICENSE'; 'All rights reserved 2024 jouniverse (see also below).'; ''; 'ACKNOWLEDGEMENTS'; 'get_google_map was created by Val Schmidt and deg2utm was created by Rafael Palacios. If conflicts of  interest arise, these functions will be replaced by built-in '; 'geoaxes functions.'; ''};
            app.ImageSunLogoAbout = uiimage(app.AboutTab);
            app.ImageSunLogoAbout.ImageSource = fullfile(pathToMLAPP, 'sun-logo.png');
            app.HyperlinkREf5 = uihyperlink(app.AboutTab);
            app.HyperlinkREf5.URL = 'https://se.mathworks.com/matlabcentral/fileexchange/10915-deg2utm';
            app.HyperlinkREf5.Text = 'deg2utm';
            app.HyperlinkRef4 = uihyperlink(app.AboutTab);
            app.HyperlinkRef4.URL = 'https://se.mathworks.com/matlabcentral/fileexchange/24113-get_google_map?s_tid=FX_rc1_behav';
            app.HyperlinkRef4.Text = 'get_google_map';
            app.HyperlinkRef3 = uihyperlink(app.AboutTab);
            app.HyperlinkRef3.URL = 'https://solar-potential-kypkjw5jmq-uc.a.run.app/';
            app.HyperlinkRef3.Text = 'Solar API Demo web app';
            app.HyperlinkRef2 = uihyperlink(app.AboutTab);
            app.HyperlinkRef2.URL = 'https://cloud.google.com/blog/products/maps-platform/our-solar-api-now-covers-more-rooftops-worldwide';
            app.HyperlinkRef2.Text = 'Our Solar API now covers more rooftops worldwide';
            app.HyperlinkRef1 = uihyperlink(app.AboutTab);
            app.HyperlinkRef1.URL = 'https://developers.google.com/maps/documentation/solar';
            app.HyperlinkRef1.Text = 'Google Maps Solar API';
            app.PVPROLabelTitle = uilabel(app.AboutTab);
            app.PVPROLabelTitle.Text = 'PV PRO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = google_solar_api_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end